classdef Chay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SchematicofSomeModelParametersPanel  matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        FixedParametersCheckBox         matlab.ui.control.CheckBox
        ExportDataTableButton_3         matlab.ui.control.Button
        ExportPlotButton_3              matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        AngleField_2                    matlab.ui.control.NumericEditField
        AngleSlider_2                   matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_8  matlab.ui.control.Label
        rField                          matlab.ui.control.NumericEditField
        rSlider                         matlab.ui.control.Slider
        zmLabel_2                       matlab.ui.control.Label
        VerticalProfileModelParametersPanel  matlab.ui.container.Panel
        zrField_2                       matlab.ui.control.NumericEditField
        zrSlider_2                      matlab.ui.control.Slider
        RadiallydependentheightofmaximumoutflowvelocitymLabel_2  matlab.ui.control.Label
        Image_8                         matlab.ui.control.Image
        TimeField_2                     matlab.ui.control.NumericEditField
        TimeSlider_2                    matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_7  matlab.ui.control.Label
        Image_6                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        RadiusofmaximumhorizontalvelocitykmLabel_2  matlab.ui.control.Label
        TotaltimeField_2                matlab.ui.control.NumericEditField
        TotaltimeSlider_2               matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_4  matlab.ui.control.Label
        rtField_2                       matlab.ui.control.NumericEditField
        rtSlider_2                      matlab.ui.control.Slider
        TimedependentradiusofmaximumoutflowvelocitykmLabel_2  matlab.ui.control.Label
        utransField_2                   matlab.ui.control.NumericEditField
        utransSlider_2                  matlab.ui.control.Slider
        TranslationalvelocityofstormmsLabel_2  matlab.ui.control.Label
        IntensificationtimeField_2      matlab.ui.control.NumericEditField
        IntensificationtimeSlider_2     matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_3  matlab.ui.control.Label
        ShapingfunctionvariableField_2  matlab.ui.control.NumericEditField
        ShapingfunctionvariableSlider_2  matlab.ui.control.Slider
        ShapingfunctionvariableSliderLabel_2  matlab.ui.control.Label
        MaxrSlider_2                    matlab.ui.control.Slider
        MaxrField_2                     matlab.ui.control.NumericEditField
        MaxoutflowvelField_2            matlab.ui.control.NumericEditField
        MaxoutflowvelSlider_2           matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel_2  matlab.ui.control.Label
        MaxzField_2                     matlab.ui.control.NumericEditField
        MaxzSlider_2                    matlab.ui.control.Slider
        HeightofmaximumoutflowvelocitymLabel_2  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        AngleField                      matlab.ui.control.NumericEditField
        AngleSlider                     matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_6  matlab.ui.control.Label
        zField                          matlab.ui.control.NumericEditField
        zSlider                         matlab.ui.control.Slider
        zmLabel                         matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        ExportDataTableButton_2         matlab.ui.control.Button
        ExportPlotButton_2              matlab.ui.control.Button
        RadialProfileModelParametersPanel  matlab.ui.container.Panel
        zrField                         matlab.ui.control.NumericEditField
        zrSlider                        matlab.ui.control.Slider
        RadiallydependentheightofmaximumoutflowvelocitymLabel  matlab.ui.control.Label
        Image_7                         matlab.ui.control.Image
        TimeField                       matlab.ui.control.NumericEditField
        TimeSlider                      matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_5  matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        TotaltimeField                  matlab.ui.control.NumericEditField
        TotaltimeSlider                 matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_2  matlab.ui.control.Label
        rtField                         matlab.ui.control.NumericEditField
        rtSlider                        matlab.ui.control.Slider
        RadialrateofchangeofradiusofmaximumhorizontalvelocitymminLabel  matlab.ui.control.Label
        utransField                     matlab.ui.control.NumericEditField
        utransSlider                    matlab.ui.control.Slider
        TranslationalvelocityofstormmsLabel  matlab.ui.control.Label
        IntensificationtimeField        matlab.ui.control.NumericEditField
        IntensificationtimeSlider       matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel  matlab.ui.control.Label
        ShapingfunctionvariableField    matlab.ui.control.NumericEditField
        ShapingfunctionvariableSlider   matlab.ui.control.Slider
        ShapingfunctionvariableSliderLabel  matlab.ui.control.Label
        MaxrSlider                      matlab.ui.control.Slider
        RadiusofmaximumhorizontalvelocitykmLabel  matlab.ui.control.Label
        MaxrField                       matlab.ui.control.NumericEditField
        MaxzField                       matlab.ui.control.NumericEditField
        MaxoutflowvelField              matlab.ui.control.NumericEditField
        MaxoutflowvelSlider             matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel  matlab.ui.control.Label
        MaxzSlider                      matlab.ui.control.Slider
        HeightofmaximumoutflowvelocitymLabel  matlab.ui.control.Label
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlottangentialcomponentCheckBox  matlab.ui.control.CheckBox
        PlotverticalcomponentCheckBox   matlab.ui.control.CheckBox
        PlotradialcomponentCheckBox     matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        Chayetal2006Label               matlab.ui.control.Label
        VerticalAxes                    matlab.ui.control.UIAxes
        HorizontalAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)

        function u_mag = compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang)
            % Horizontal downburst velocity (Equation 9)
            u_r = i_f .* ((ur_max .* r) ./ r_t) .* ((exp(c_1 * (z/z_r)) - exp(c_2 * (z/z_r))) / (exp(c_1) - exp(c_2))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a))) + (u_trans*cosd(ang));
            %u_dr = i_f .* ((ur_max .* r) ./ r_t) .* ((exp(c_1 * (z/z_r)) - exp(c_2 * (z/z_r))) / (exp(c_1) - exp(c_2))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a)));

            % Tangential velocity
            u_theta = u_trans*sind(ang);

            % Vertical downburst velocity (Equation 10)
            u_z = -2 * i_f .* ((ur_max * z_max) ./ r_t) .* (((1/c_1)*(exp(c_1 * (z/z_r)) - 1) - (1/c_2)*(exp(c_2 * (z/z_r)) - 1)) / (exp(c_1) - exp(c_2))) .* (1 - (0.5 * ((r.^2/r_t^2).^a))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a)));
    
            % Downburst velocity magnitude
            u_mag = sqrt(u_r.^2 + u_theta^2 + u_z.^2);
        end

        function u_r = compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang)
            % Horizontal downburst velocity (Equation 9)
            u_r = i_f .* ((ur_max .* r) ./ r_t) .* ((exp(c_1 * (z/z_r)) - exp(c_2 * (z/z_r))) / (exp(c_1) - exp(c_2))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a))) + (u_trans*cosd(ang));
        end
        
        function u_z = compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f)
            % Vertical downburst velocity (Equation 10)
            u_z = -2 * i_f .* ((ur_max * z_max) ./ r_t) .* (((1/c_1)*(exp(c_1 * (z/z_r)) - 1) - (1/c_2)*(exp(c_2 * (z/z_r)) - 1)) / (exp(c_1) - exp(c_2))) .* (1 - (0.5 * ((r.^2/r_t^2).^a))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a)));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller4)
            % Store selection menu app object
            app.CallingApp = caller4;
            
            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlotradialcomponentCheckBox.Value = 1;
            app.PlottangentialcomponentCheckBox.Value = 1;
            app.PlotverticalcomponentCheckBox.Value = 1;
            app.FixedParametersCheckBox.Value = 1;

            % Define parameters for radial profile
            r = 0:1:10000;
            t = app.TimeSlider.Value; % Current time
            t_tot = app.TotaltimeSlider.Value; % Storm duration
            t_int = app.IntensificationtimeSlider.Value; % Intensification time
            if t <= t_int % Intensity factor (Equation 8)
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000; % Denoted as r_p in paper
            z_max = app.MaxzSlider.Value; % Denoted as z_m in paper
            z_r = app.zrSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_tr = app.rtSlider.Value; % Radial rate of change of r_max
            r_t = r_max + r_tr*t; % Time dependent r_m
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value; % Denoted as alpha in paper
            ang = app.AngleSlider.Value; % Azimuth angle
            u_theta = zeros(1, length(r)); % Tangential velocity
            for i = 1:length(r)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), 'k', r, compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), 'r', r, u_theta, 'g', r, compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            p(4).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % Define parameters for vertical profile
            z = 0:1:1000;
            t = app.TimeSlider_2.Value;
            t_tot = app.TotaltimeSlider_2.Value;
            t_int = app.IntensificationtimeSlider_2.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            r = app.rSlider.Value * 1000;
            r_max = app.MaxrSlider_2.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider_2.Value;
            z_r = app.zrSlider_2.Value;
            ur_max = app.MaxoutflowvelSlider_2.Value;
            u_trans = app.utransSlider_2.Value;
            r_tr = app.rtSlider_2.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider_2.Value;
            ang = app.AngleSlider_2.Value;
            u_theta = zeros(1, length(z));
            for i = 1:length(z)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Plot vertical profile
            plot(app.VerticalAxes, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), z, 'k', compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), z, 'r', u_theta, z, 'g', compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            p(4).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(app.VerticalAxes, 'Location', 'southoutside');
        end

        % Value changed function: AngleSlider, IntensificationtimeSlider, 
        % ...and 10 other components
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            t = app.TimeSlider.Value;
            t_tot = app.TotaltimeSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider.Value;
            z_r = app.zrSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_tr = app.rtSlider.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;
            ang = app.AngleSlider.Value;
            u_theta = zeros(1, length(r)); % Tangential velocity
            for i = 1:length(r)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), 'k', r, compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), 'r', r, u_theta, 'g', r, compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            p(4).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(4).Visible = 'on';
            else p(4).Visible = 'off';
            end
            if app.PlotradialcomponentCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlottangentialcomponentCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalcomponentCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelField.Value = app.MaxoutflowvelSlider.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrField.Value = app.MaxrSlider.Value;
            else
            end
            if app.MaxzField.Value ~= app.MaxzSlider.Value
                app.MaxzField.Value = app.MaxzSlider.Value;
            else
            end
            if app.utransField.Value ~= app.utransSlider.Value
                app.utransField.Value = app.utransSlider.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeField.Value = app.IntensificationtimeSlider.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeField.Value = app.TotaltimeSlider.Value;
            else
            end
            if app.ShapingfunctionvariableField.Value ~= app.ShapingfunctionvariableSlider.Value
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableSlider.Value;
            else
            end
            if app.rtField.Value ~= app.rtSlider.Value
                app.rtField.Value = app.rtSlider.Value;
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zField.Value = app.zSlider.Value;
            end
            if app.TimeField.Value ~= app.TimeSlider.Value
                app.TimeField.Value = app.TimeSlider.Value;
            end
            if app.AngleField.Value ~= app.AngleSlider.Value
                app.AngleField.Value = app.AngleSlider.Value;
            end
            if app.zrField.Value ~= app.zrSlider.Value
                app.zrField.Value = app.zrSlider.Value;
            end

            % Updates if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxoutflowvelSlider_2.Value = app.MaxoutflowvelSlider.Value;
                app.MaxoutflowvelField_2.Value = app.MaxoutflowvelField.Value;
                app.MaxrSlider_2.Value = app.MaxrSlider.Value;
                app.MaxrField_2.Value = app.MaxrField.Value;
                app.MaxzSlider_2.Value = app.MaxzSlider.Value;
                app.MaxzField_2.Value = app.MaxzField.Value;
                app.utransSlider_2.Value = app.utransSlider.Value;
                app.utransField_2.Value = app.utransField.Value;
                app.IntensificationtimeSlider_2.Value = app.IntensificationtimeSlider.Value;
                app.IntensificationtimeField_2.Value = app.IntensificationtimeField.Value;
                app.TotaltimeSlider_2.Value = app.TotaltimeSlider.Value;
                app.TotaltimeField_2.Value = app.TotaltimeField.Value;
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableSlider.Value;
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableField.Value;
                app.rtSlider_2.Value = app.rtSlider.Value;
                app.rtField_2.Value = app.rtField.Value;
                app.TimeSlider_2.Value = app.TimeSlider.Value;
                app.TimeField_2.Value = app.TimeField.Value;
                app.AngleSlider_2.Value = app.AngleSlider.Value;
                app.AngleField_2.Value = app.AngleField.Value;
                app.zrSlider_2.Value = app.zrSlider.Value;
                app.zrField_2.Value = app.zrField.Value;

                % Update vertical profile
                z = 0:1:1000;
                t = app.TimeSlider_2.Value;
                t_tot = app.TotaltimeSlider_2.Value;
                t_int = app.IntensificationtimeSlider_2.Value;
                if t <= t_int
                    i_f = t / t_int;
                else 
                    i_f = exp((-(t-t_int))/t_tot);   
                end
                r = app.rSlider.Value * 1000;
                r_max = app.MaxrSlider_2.Value * 1000; % Denoted as rp in paper
                z_max = app.MaxzSlider_2.Value;
                z_r = app.zrSlider_2.Value;
                ur_max = app.MaxoutflowvelSlider_2.Value;
                u_trans = app.utransSlider_2.Value;
                r_tr = app.rtSlider_2.Value;
                r_t = r_max + r_tr*t;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableSlider_2.Value;
                ang = app.AngleSlider_2.Value;
                u_theta = zeros(1, length(z));
                for i = 1:length(z)
                    u_theta(i) = u_trans*sind(ang); 
                end
    
                % Plot vertical profile
                plot(app.VerticalAxes, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), z, 'k', compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), z, 'r', u_theta, z, 'g', compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                p(4).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(4).Visible = 'on';
                else p(4).Visible = 'off';
                end
                if app.PlotradialcomponentCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlottangentialcomponentCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalcomponentCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            end
        end

        % Value changed function: AngleField, IntensificationtimeField, 
        % ...and 10 other components
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            t = app.TimeField.Value;
            t_tot = app.TotaltimeField.Value;
            t_int = app.IntensificationtimeField.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            z = app.zField.Value;
            r_max = app.MaxrField.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzField.Value;
            z_r = app.zrField.Value;
            ur_max = app.MaxoutflowvelField.Value;
            u_trans = app.utransField.Value;
            r_tr = app.rtField.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField.Value;
            ang = app.AngleField.Value;
            u_theta = zeros(1, length(r)); % Tangential velocity
            for i = 1:length(r)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), 'k', r, compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), 'r', r, u_theta, 'g', r, compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            p(4).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(4).Visible = 'on';
            else p(4).Visible = 'off';
            end
            if app.PlotradialcomponentCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlottangentialcomponentCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalcomponentCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update sliders values to match fields
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelField.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrSlider.Value = app.MaxrField.Value;
            else
            end
            if app.MaxzField.Value ~= app.MaxzSlider.Value
                app.MaxzSlider.Value = app.MaxzField.Value;
            else
            end
            if app.utransField.Value ~= app.utransSlider.Value
                app.utransSlider.Value = app.utransField.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeSlider.Value = app.IntensificationtimeField.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeSlider.Value = app.TotaltimeField.Value;
            else
            end
            if app.ShapingfunctionvariableField.Value ~= app.ShapingfunctionvariableSlider.Value
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableField.Value;
            else
            end
            if app.rtField.Value ~= app.rtSlider.Value
                app.rtSlider.Value = app.rtField.Value;
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            end
            if app.TimeField.Value ~= app.TimeSlider.Value
                app.TimeSlider.Value = app.TimeField.Value;
            end
            if app.AngleField.Value ~= app.AngleSlider.Value
                app.AngleSlider.Value = app.AngleField.Value;
            end
            if app.zrField.Value ~= app.zrSlider.Value
                app.zrSlider.Value = app.zrField.Value;
            end

            % Updates if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxoutflowvelSlider_2.Value = app.MaxoutflowvelSlider.Value;
                app.MaxoutflowvelField_2.Value = app.MaxoutflowvelField.Value;
                app.MaxrSlider_2.Value = app.MaxrSlider.Value;
                app.MaxrField_2.Value = app.MaxrField.Value;
                app.MaxzSlider_2.Value = app.MaxzSlider.Value;
                app.MaxzField_2.Value = app.MaxzField.Value;
                app.utransSlider_2.Value = app.utransSlider.Value;
                app.utransField_2.Value = app.utransField.Value;
                app.IntensificationtimeSlider_2.Value = app.IntensificationtimeSlider.Value;
                app.IntensificationtimeField_2.Value = app.IntensificationtimeField.Value;
                app.TotaltimeSlider_2.Value = app.TotaltimeSlider.Value;
                app.TotaltimeField_2.Value = app.TotaltimeField.Value;
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableSlider.Value;
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableField.Value;
                app.rtSlider_2.Value = app.rtSlider.Value;
                app.rtField_2.Value = app.rtField.Value;
                app.TimeSlider_2.Value = app.TimeSlider.Value;
                app.TimeField_2.Value = app.TimeField.Value;
                app.AngleSlider_2.Value = app.AngleSlider.Value;
                app.AngleField_2.Value = app.AngleField.Value;
                app.zrSlider_2.Value = app.zrSlider.Value;
                app.zrField_2.Value = app.zrField.Value;

                % Update vertical profile
                z = 0:1:1000;
                t = app.TimeSlider_2.Value;
                t_tot = app.TotaltimeSlider_2.Value;
                t_int = app.IntensificationtimeSlider_2.Value;
                if t <= t_int
                    i_f = t / t_int;
                else 
                    i_f = exp((-(t-t_int))/t_tot);   
                end
                r = app.rSlider.Value * 1000;
                r_max = app.MaxrSlider_2.Value * 1000; % Denoted as rp in paper
                z_max = app.MaxzSlider_2.Value;
                z_r = app.zrSlider_2.Value;
                ur_max = app.MaxoutflowvelSlider_2.Value;
                u_trans = app.utransSlider_2.Value;
                r_tr = app.rtSlider_2.Value;
                r_t = r_max + r_tr*t;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableSlider_2.Value;
                ang = app.AngleSlider_2.Value;
                u_theta = zeros(1, length(z));
                for i = 1:length(z)
                    u_theta(i) = u_trans*sind(ang); 
                end
    
                % Plot vertical profile
                plot(app.VerticalAxes, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), z, 'k', compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), z, 'r', u_theta, z, 'g', compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                p(4).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(4).Visible = 'on';
                else p(4).Visible = 'off';
                end
                if app.PlotradialcomponentCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlottangentialcomponentCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalcomponentCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            end
        end

        % Value changed function: AngleSlider_2, 
        % ...and 11 other components
        function SliderValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            t = app.TimeSlider_2.Value;
            t_tot = app.TotaltimeSlider_2.Value;
            t_int = app.IntensificationtimeSlider_2.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            r = app.rSlider.Value * 1000;
            r_max = app.MaxrSlider_2.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider_2.Value;
            z_r = app.zrSlider_2.Value;
            ur_max = app.MaxoutflowvelSlider_2.Value;
            u_trans = app.utransSlider_2.Value;
            r_tr = app.rtSlider_2.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider_2.Value;
            ang = app.AngleSlider_2.Value;
            u_theta = zeros(1, length(z));
            for i = 1:length(z)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Plot vertical profile
            plot(app.VerticalAxes, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), z, 'k', compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), z, 'r', u_theta, z, 'g', compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            p(4).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(4).Visible = 'on';
            else p(4).Visible = 'off';
            end
            if app.PlotradialcomponentCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlottangentialcomponentCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalcomponentCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxoutflowvelField_2.Value ~= app.MaxoutflowvelSlider_2.Value
                app.MaxoutflowvelField_2.Value = app.MaxoutflowvelSlider_2.Value;
            else
            end
            if app.MaxrField_2.Value ~= app.MaxrSlider_2.Value
                app.MaxrField_2.Value = app.MaxrSlider_2.Value;
            else
            end
            if app.MaxzField_2.Value ~= app.MaxzSlider_2.Value
                app.MaxzField_2.Value = app.MaxzSlider_2.Value;
            else
            end
            if app.utransField_2.Value ~= app.utransSlider_2.Value
                app.utransField_2.Value = app.utransSlider_2.Value;
            else
            end
            if app.IntensificationtimeField_2.Value ~= app.IntensificationtimeSlider_2.Value
                app.IntensificationtimeField_2.Value = app.IntensificationtimeSlider_2.Value;
            else
            end
            if app.TotaltimeField_2.Value ~= app.TotaltimeSlider_2.Value
                app.TotaltimeField_2.Value = app.TotaltimeSlider_2.Value;
            else
            end
            if app.ShapingfunctionvariableField_2.Value ~= app.ShapingfunctionvariableSlider_2.Value
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableSlider_2.Value;
            else
            end
            if app.rtField_2.Value ~= app.rtSlider_2.Value
                app.rtField_2.Value = app.rtSlider_2.Value;
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rField.Value = app.rSlider.Value;
            end
            if app.TimeField_2.Value ~= app.TimeSlider_2.Value
                app.TimeField_2.Value = app.TimeSlider_2.Value;
            end
            if app.AngleField_2.Value ~= app.AngleSlider_2.Value
                app.AngleField_2.Value = app.AngleSlider_2.Value;
            end
            if app.zrField_2.Value ~= app.zrSlider_2.Value
                app.zrField_2.Value = app.zrSlider_2.Value;
            end

            % Updates if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelSlider_2.Value;
                app.MaxoutflowvelField.Value = app.MaxoutflowvelField_2.Value;
                app.MaxrSlider.Value = app.MaxrSlider_2.Value;
                app.MaxrField.Value = app.MaxrField_2.Value;
                app.MaxzSlider.Value = app.MaxzSlider_2.Value;
                app.MaxzField.Value = app.MaxzField_2.Value;
                app.utransSlider.Value = app.utransSlider_2.Value;
                app.utransField.Value = app.utransField_2.Value;
                app.IntensificationtimeSlider.Value = app.IntensificationtimeSlider_2.Value;
                app.IntensificationtimeField.Value = app.IntensificationtimeField_2.Value;
                app.TotaltimeSlider.Value = app.TotaltimeSlider_2.Value;
                app.TotaltimeField.Value = app.TotaltimeField_2.Value;
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableSlider_2.Value;
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableField_2.Value;
                app.rtSlider.Value = app.rtSlider_2.Value;
                app.rtField.Value = app.rtField_2.Value;
                app.TimeSlider.Value = app.TimeSlider_2.Value;
                app.TimeField.Value = app.TimeField_2.Value;
                app.AngleSlider.Value = app.AngleSlider_2.Value;
                app.AngleField.Value = app.AngleField_2.Value;
                app.zrSlider.Value = app.zrSlider_2.Value;
                app.zrField.Value = app.zrField_2.Value;

                % Update radial profile
                r = 0:1:10000;
                t = app.TimeSlider.Value;
                t_tot = app.TotaltimeSlider.Value;
                t_int = app.IntensificationtimeSlider.Value;
                if t <= t_int
                    i_f = t / t_int;
                else 
                    i_f = exp((-(t-t_int))/t_tot);   
                end
                z = app.zSlider.Value;
                r_max = app.MaxrSlider.Value * 1000; % Denoted as rp in paper
                z_max = app.MaxzSlider.Value;
                z_r = app.zrSlider.Value;
                ur_max = app.MaxoutflowvelSlider.Value;
                u_trans = app.utransSlider.Value;
                r_tr = app.rtSlider.Value;
                r_t = r_max + r_tr*t;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableSlider.Value;
                ang = app.AngleSlider.Value;
                u_theta = zeros(1, length(r)); % Tangential velocity
                for i = 1:length(r)
                    u_theta(i) = u_trans*sind(ang); 
                end
    
                % Plot radial profile
                plot(app.HorizontalAxes, r, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), 'k', r, compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), 'r', r, u_theta, 'g', r, compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                p(4).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
                legend(app.HorizontalAxes, 'Location', 'southoutside');
    
                % Adjust axis for radial profile
                r_lim = 2 * r_max;
                if (r_lim+2000) > 10000
                    xlim(app.HorizontalAxes, [0, 10000]);
                else
                    xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
                end
    
                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(4).Visible = 'on';
                else p(4).Visible = 'off';
                end
                if app.PlotradialcomponentCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlottangentialcomponentCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalcomponentCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            end
        end

        % Value changed function: AngleField_2, 
        % ...and 11 other components
        function FieldValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            t = app.TimeField_2.Value;
            t_tot = app.TotaltimeField_2.Value;
            t_int = app.IntensificationtimeField_2.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            r = app.rField.Value * 1000;
            r_max = app.MaxrField_2.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzField_2.Value;
            z_r = app.zrField_2.Value;
            ur_max = app.MaxoutflowvelField_2.Value;
            u_trans = app.utransField_2.Value;
            r_tr = app.rtField_2.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField_2.Value;
            ang = app.AngleField_2.Value;
            u_theta = zeros(1, length(z));
            for i = 1:length(z)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Plot vertical profile
            plot(app.VerticalAxes, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), z, 'k', compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), z, 'r', u_theta, z, 'g', compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            p(4).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(4).Visible = 'on';
            else p(4).Visible = 'off';
            end
            if app.PlotradialcomponentCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlottangentialcomponentCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalcomponentCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update slider values to match fields
            if app.MaxoutflowvelField_2.Value ~= app.MaxoutflowvelSlider_2.Value
                app.MaxoutflowvelSlider_2.Value = app.MaxoutflowvelField_2.Value;
            else
            end
            if app.MaxrField_2.Value ~= app.MaxrSlider_2.Value
                app.MaxrSlider_2.Value = app.MaxrField_2.Value;
            else
            end
            if app.MaxzField_2.Value ~= app.MaxzSlider_2.Value
                app.MaxzSlider_2.Value = app.MaxzField_2.Value;
            else
            end
            if app.utransField_2.Value ~= app.utransSlider_2.Value
                app.utransSlider_2.Value = app.utransField_2.Value;
            else
            end
            if app.IntensificationtimeField_2.Value ~= app.IntensificationtimeSlider_2.Value
                app.IntensificationtimeSlider_2.Value = app.IntensificationtimeField_2.Value;
            else
            end
            if app.TotaltimeField_2.Value ~= app.TotaltimeSlider_2.Value
                app.TotaltimeSlider_2.Value = app.TotaltimeField_2.Value;
            else
            end
            if app.ShapingfunctionvariableField_2.Value ~= app.ShapingfunctionvariableSlider_2.Value
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableField_2.Value;
            else
            end
            if app.rtField_2.Value ~= app.rtSlider_2.Value
                app.rtSlider_2.Value = app.rtField_2.Value;
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            end
            if app.TimeField_2.Value ~= app.TimeSlider_2.Value
                app.TimeSlider_2.Value = app.TimeField_2.Value;
            end
            if app.AngleField_2.Value ~= app.AngleSlider_2.Value
                app.AngleSlider_2.Value = app.AngleField_2.Value;
            end
            if app.zrField_2.Value ~= app.zrSlider_2.Value
                app.zrSlider_2.Value = app.zrField_2.Value;
            end

            % Updates if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelSlider_2.Value;
                app.MaxoutflowvelField.Value = app.MaxoutflowvelField_2.Value;
                app.MaxrSlider.Value = app.MaxrSlider_2.Value;
                app.MaxrField.Value = app.MaxrField_2.Value;
                app.MaxzSlider.Value = app.MaxzSlider_2.Value;
                app.MaxzField.Value = app.MaxzField_2.Value;
                app.utransSlider.Value = app.utransSlider_2.Value;
                app.utransField.Value = app.utransField_2.Value;
                app.IntensificationtimeSlider.Value = app.IntensificationtimeSlider_2.Value;
                app.IntensificationtimeField.Value = app.IntensificationtimeField_2.Value;
                app.TotaltimeSlider.Value = app.TotaltimeSlider_2.Value;
                app.TotaltimeField.Value = app.TotaltimeField_2.Value;
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableSlider_2.Value;
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableField_2.Value;
                app.rtSlider.Value = app.rtSlider_2.Value;
                app.rtField.Value = app.rtField_2.Value;
                app.TimeSlider.Value = app.TimeSlider_2.Value;
                app.TimeField.Value = app.TimeField_2.Value;
                app.AngleSlider.Value = app.AngleSlider_2.Value;
                app.AngleField.Value = app.AngleField_2.Value;
                app.zrSlider.Value = app.zrSlider_2.Value;
                app.zrField.Value = app.zrField_2.Value;

                % Update radial profile
                r = 0:1:10000;
                t = app.TimeSlider.Value;
                t_tot = app.TotaltimeSlider.Value;
                t_int = app.IntensificationtimeSlider.Value;
                if t <= t_int
                    i_f = t / t_int;
                else 
                    i_f = exp((-(t-t_int))/t_tot);   
                end
                z = app.zSlider.Value;
                r_max = app.MaxrSlider.Value * 1000; % Denoted as rp in paper
                z_max = app.MaxzSlider.Value;
                z_r = app.zrSlider.Value;
                ur_max = app.MaxoutflowvelSlider.Value;
                u_trans = app.utransSlider.Value;
                r_tr = app.rtSlider.Value;
                r_t = r_max + r_tr*t;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableSlider.Value;
                ang = app.AngleSlider.Value;
                u_theta = zeros(1, length(r)); % Tangential velocity
                for i = 1:length(r)
                    u_theta(i) = u_trans*sind(ang); 
                end
    
                % Plot radial profile
                plot(app.HorizontalAxes, r, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), 'k', r, compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), 'r', r, u_theta, 'g', r, compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                p(4).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
                legend(app.HorizontalAxes, 'Location', 'southoutside');
    
                % Adjust axis for radial profile
                r_lim = 2 * r_max;
                if (r_lim+2000) > 10000
                    xlim(app.HorizontalAxes, [0, 10000]);
                else
                    xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
                end
    
                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(4).Visible = 'on';
                else p(4).Visible = 'off';
                end
                if app.PlotradialcomponentCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlottangentialcomponentCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalcomponentCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            end
        end

        % Value changed function: PlotmagnitudeCheckBox, 
        % ...and 3 other components
        function CheckBoxValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            t = app.TimeSlider.Value;
            t_tot = app.TotaltimeSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider.Value;
            z_r = app.zrSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_tr = app.rtSlider.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;
            ang = app.AngleSlider.Value;
            u_theta = zeros(1, length(r)); % Tangential velocity
            for i = 1:length(r)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), 'k', r, compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), 'r', r, u_theta, 'g', r, compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            p(4).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(4).Visible = 'on';
            else p(4).Visible = 'off';
            end
            if app.PlotradialcomponentCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlottangentialcomponentCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalcomponentCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end


            % Define parameters for vertical profile
            z = 0:1:1000;
            t = app.TimeSlider_2.Value;
            t_tot = app.TotaltimeSlider_2.Value;
            t_int = app.IntensificationtimeSlider_2.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            r = app.rSlider.Value * 1000;
            r_max = app.MaxrSlider_2.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider_2.Value;
            z_r = app.zrSlider_2.Value;
            ur_max = app.MaxoutflowvelSlider_2.Value;
            u_trans = app.utransSlider_2.Value;
            r_tr = app.rtSlider_2.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider_2.Value;
            ang = app.AngleSlider_2.Value;
            u_theta = zeros(1, length(z));
            for i = 1:length(z)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Plot vertical profile
            plot(app.VerticalAxes, compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang), z, 'k', compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang), z, 'r', u_theta, z, 'g', compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            p(4).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(4).Visible = 'on';
            else p(4).Visible = 'off';
            end
            if app.PlotradialcomponentCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlottangentialcomponentCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalcomponentCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the radial plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            t = app.TimeSlider.Value;
            t_tot = app.TotaltimeSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider.Value;
            z_r = app.zrSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_tr = app.rtSlider.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;
            ang = app.AngleSlider.Value;
            u_theta = zeros(1, length(r)); % Tangential velocity
            for i = 1:length(r)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Put x axis values into an array
            r = [0:1:10000];
            % Compute velocities and insert into arrays
            m = [compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang)];
            h = [compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang)];
            t = [u_theta];
            v = [compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f)];

            % Arrange arrays into a table
            ar = [r; m; h; t; v];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2", "Var3", "Var4", "Var5"], ["Distance from downdraft center [m]","Magnitude [m/s]","Radial Component [m/s]","Tangential Component [m/s]","Vertical Component [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(tbl, filenameFull)
            end
        end

        % Button pushed function: ExportPlotButton_3
        function ExportPlotButton_2Pushed(app, event)
            % Create a temporary figure of the radial plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.VerticalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.VerticalAxes.XLim;
            figAxes.YLim = app.VerticalAxes.YLim;
            figAxes.ZLim = app.VerticalAxes.ZLim;
            figAxes.DataAspectRatio = app.VerticalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Radial Component', 'Tangential Component', 'Vertical Component');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Vertical Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_3
        function ExportDataTableButton_2Pushed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            t = app.TimeSlider_2.Value;
            t_tot = app.TotaltimeSlider_2.Value;
            t_int = app.IntensificationtimeSlider_2.Value;
            if t <= t_int
                i_f = t / t_int;
            else 
                i_f = exp((-(t-t_int))/t_tot);   
            end
            r = app.rSlider.Value * 1000;
            r_max = app.MaxrSlider_2.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider_2.Value;
            z_r = app.zrSlider_2.Value;
            ur_max = app.MaxoutflowvelSlider_2.Value;
            u_trans = app.utransSlider_2.Value;
            r_tr = app.rtSlider_2.Value;
            r_t = r_max + r_tr*t;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider_2.Value;
            ang = app.AngleSlider_2.Value;
            u_theta = zeros(1, length(z)); 
            for i = 1:length(z)
                u_theta(i) = u_trans*sind(ang); 
            end

            % Put y axis values into an array
            z = [0:1:1000];
            % Compute velocities and insert into arrays
            m = [compute_umag(app, r, z, ur_max, u_trans, r_t, z_r, z_max, c_1, c_2, a, i_f, ang)];
            h = [compute_ur(app, r, z, ur_max, u_trans, r_t, z_r, c_1, c_2, a, i_f, ang)];
            t = [u_theta];
            v = [compute_uz(app, r, z, ur_max, r_t, z_r, z_max, c_1, c_2, a, i_f)];

            % Arrange arrays into a table
            ar = [m; h; t; v; z];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2", "Var3", "Var4", "Var5"], ["Magnitude [m/s]","Radial Component [m/s]","Tangential Component [m/s]", "Vertical Component [m/s]", "Height above ground [m]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(tbl, filenameFull)
            end
        end

        % Close request function: UIFigure
        function ChayCloseRequest(app, event)
            % Reenable Chay button
            app.CallingApp.Chayetal2006Button.Enable = 'on';

            % Close Chay window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @ChayCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.VerticalAxes = uiaxes(app.UIFigure);
            title(app.VerticalAxes, 'Vertical Profile of Velocity')
            xlabel(app.VerticalAxes, 'Velocity [m/s]')
            ylabel(app.VerticalAxes, 'Height above ground [m]')
            zlabel(app.VerticalAxes, 'Z')
            app.Chayetal2006Label = uilabel(app.UIFigure);
            app.Chayetal2006Label.Text = 'Chay et al. (2006)';
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlotradialcomponentCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotradialcomponentCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotradialcomponentCheckBox.Text = 'Plot radial component';
            app.PlotverticalcomponentCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotverticalcomponentCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotverticalcomponentCheckBox.Text = 'Plot vertical component';
            app.PlottangentialcomponentCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlottangentialcomponentCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlottangentialcomponentCheckBox.Text = 'Plot tangential component';
            app.RadialProfileModelParametersPanel = uipanel(app.UIFigure);
            app.RadialProfileModelParametersPanel.Title = 'Radial Profile: Model Parameters';
            app.HeightofmaximumoutflowvelocitymLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.HeightofmaximumoutflowvelocitymLabel.Text = 'Height of maximum outflow velocity [m]';
            app.MaxzSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxzSlider.Limits = [0 1000];
            app.MaxzSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxzSlider.Value = 100;
            app.MaximumhorizontalvelocitymsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxoutflowvelSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxoutflowvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxoutflowvelSlider.Value = 40;
            app.MaxoutflowvelField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxoutflowvelField.Limits = [0 100];
            app.MaxoutflowvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxoutflowvelField.Value = 40;
            app.MaxzField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxzField.Limits = [0 1000];
            app.MaxzField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxzField.Value = 100;
            app.MaxrField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxrField.Limits = [0 5];
            app.MaxrField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxrField.Tooltip = {''};
            app.MaxrField.Value = 1;
            app.RadiusofmaximumhorizontalvelocitykmLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.RadiusofmaximumhorizontalvelocitykmLabel.Tooltip = {''};
            app.RadiusofmaximumhorizontalvelocitykmLabel.Text = {'Radius of maximum horizontal'; 'velocity [km]'};
            app.MaxrSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxrSlider.Limits = [0 5];
            app.MaxrSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxrSlider.Tooltip = {''};
            app.MaxrSlider.Value = 1;
            app.ShapingfunctionvariableSliderLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.ShapingfunctionvariableSliderLabel.Tooltip = {''};
            app.ShapingfunctionvariableSliderLabel.Text = 'Shaping function';
            app.ShapingfunctionvariableSlider = uislider(app.RadialProfileModelParametersPanel);
            app.ShapingfunctionvariableSlider.Limits = [1 4];
            app.ShapingfunctionvariableSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ShapingfunctionvariableSlider.Tooltip = {''};
            app.ShapingfunctionvariableSlider.Value = 2;
            app.ShapingfunctionvariableField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.ShapingfunctionvariableField.Limits = [1 4];
            app.ShapingfunctionvariableField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ShapingfunctionvariableField.Tooltip = {''};
            app.ShapingfunctionvariableField.Value = 2;
            app.LengthofstormintensificationminSliderLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel.Tooltip = {''};
            app.LengthofstormintensificationminSliderLabel.Text = 'Storm intensification time [min]';
            app.IntensificationtimeSlider = uislider(app.RadialProfileModelParametersPanel);
            app.IntensificationtimeSlider.Limits = [0 15];
            app.IntensificationtimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.IntensificationtimeSlider.Tooltip = {''};
            app.IntensificationtimeSlider.Value = 5;
            app.IntensificationtimeField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.IntensificationtimeField.Limits = [0 15];
            app.IntensificationtimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.IntensificationtimeField.Tooltip = {''};
            app.IntensificationtimeField.Value = 5;
            app.TranslationalvelocityofstormmsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.TranslationalvelocityofstormmsLabel.Text = 'Translational velocity of storm [m/s]';
            app.utransSlider = uislider(app.RadialProfileModelParametersPanel);
            app.utransSlider.Limits = [0 30];
            app.utransSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.utransSlider.Value = 10;
            app.utransField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.utransField.Limits = [0 30];
            app.utransField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.utransField.Value = 10;
            app.RadialrateofchangeofradiusofmaximumhorizontalvelocitymminLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.RadialrateofchangeofradiusofmaximumhorizontalvelocitymminLabel.Text = {'Radial rate of change of radius '; 'of maximum horizontal velocity [m/min]'};
            app.rtSlider = uislider(app.RadialProfileModelParametersPanel);
            app.rtSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rtSlider.Value = 30;
            app.rtField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.rtField.Limits = [0 100];
            app.rtField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rtField.Value = 30;
            app.LengthofstormintensificationminSliderLabel_2 = uilabel(app.RadialProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_2.Text = 'Storm duration [min] ';
            app.TotaltimeSlider = uislider(app.RadialProfileModelParametersPanel);
            app.TotaltimeSlider.Limits = [0 30];
            app.TotaltimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TotaltimeSlider.Value = 15;
            app.TotaltimeField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.TotaltimeField.Limits = [0 30];
            app.TotaltimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TotaltimeField.Value = 15;
            app.Image = uiimage(app.RadialProfileModelParametersPanel);
            app.Image.Tooltip = {'Note that this is the radius of maximum velocity when the intensity factor is equal to 1. '};
            app.Image.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.Image_2 = uiimage(app.RadialProfileModelParametersPanel);
            app.Image_2.Tooltip = {'Default value is 2.'};
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.Image_3 = uiimage(app.RadialProfileModelParametersPanel);
            app.Image_3.Tooltip = {'Storm intensification time is typically 5 minutes. Over 90% of storms reached maximum intensification within 10 minutes (Hjelmfelt 1988; Wilson et al. 1984).'};
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.LengthofstormintensificationminSliderLabel_5 = uilabel(app.RadialProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_5.Text = 'Storm current time [min] ';
            app.TimeSlider = uislider(app.RadialProfileModelParametersPanel);
            app.TimeSlider.Limits = [0 30];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TimeSlider.Value = 10;
            app.TimeField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.TimeField.Limits = [0 30];
            app.TimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TimeField.Value = 10;
            app.Image_7 = uiimage(app.RadialProfileModelParametersPanel);
            app.Image_7.Tooltip = {'The authors have proposed values of 0 m/min and 30 m/min in case studies using this model. '};
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.RadiallydependentheightofmaximumoutflowvelocitymLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.RadiallydependentheightofmaximumoutflowvelocitymLabel.Text = {'Radially dependent height of'; 'maximum outflow velocity [m]'};
            app.zrSlider = uislider(app.RadialProfileModelParametersPanel);
            app.zrSlider.Limits = [0 1000];
            app.zrSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zrSlider.Value = 100;
            app.zrField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.zrField.Limits = [0 1000];
            app.zrField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zrField.Value = 100;
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.engstruct.2005.07.007';
            app.Hyperlink.Text = 'Corresponding Paper';
            app.Panel = uipanel(app.UIFigure);
            app.zmLabel = uilabel(app.Panel);
            app.zmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.Panel);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.Panel, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zField.Value = 100;
            app.LengthofstormintensificationminSliderLabel_6 = uilabel(app.Panel);
            app.LengthofstormintensificationminSliderLabel_6.Text = 'Azimuth angle [degrees]';
            app.AngleSlider = uislider(app.Panel);
            app.AngleSlider.Limits = [0 360];
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AngleField = uieditfield(app.Panel, 'numeric');
            app.AngleField.Limits = [0 360];
            app.AngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.VerticalProfileModelParametersPanel = uipanel(app.UIFigure);
            app.VerticalProfileModelParametersPanel.Title = 'Vertical Profile: Model Parameters';
            app.HeightofmaximumoutflowvelocitymLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.HeightofmaximumoutflowvelocitymLabel_2.Text = 'Height of maximum outflow velocity [m]';
            app.MaxzSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxzSlider_2.Limits = [0 1000];
            app.MaxzSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxzSlider_2.Value = 100;
            app.MaxzField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxzField_2.Limits = [0 1000];
            app.MaxzField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxzField_2.Value = 100;
            app.MaximumhorizontalvelocitymsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel_2.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxoutflowvelSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxoutflowvelSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxoutflowvelSlider_2.Value = 40;
            app.MaxoutflowvelField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxoutflowvelField_2.Limits = [0 100];
            app.MaxoutflowvelField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxoutflowvelField_2.Value = 40;
            app.MaxrField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxrField_2.Limits = [0 5];
            app.MaxrField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxrField_2.Tooltip = {''};
            app.MaxrField_2.Value = 1;
            app.MaxrSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxrSlider_2.Limits = [0 5];
            app.MaxrSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxrSlider_2.Tooltip = {''};
            app.MaxrSlider_2.Value = 1;
            app.ShapingfunctionvariableSliderLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.ShapingfunctionvariableSliderLabel_2.Tooltip = {''};
            app.ShapingfunctionvariableSliderLabel_2.Text = 'Shaping function';
            app.ShapingfunctionvariableSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.ShapingfunctionvariableSlider_2.Limits = [1 4];
            app.ShapingfunctionvariableSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.ShapingfunctionvariableSlider_2.Tooltip = {''};
            app.ShapingfunctionvariableSlider_2.Value = 2;
            app.ShapingfunctionvariableField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.ShapingfunctionvariableField_2.Limits = [1 4];
            app.ShapingfunctionvariableField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.ShapingfunctionvariableField_2.Tooltip = {''};
            app.ShapingfunctionvariableField_2.Value = 2;
            app.LengthofstormintensificationminSliderLabel_3 = uilabel(app.VerticalProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_3.Tooltip = {''};
            app.LengthofstormintensificationminSliderLabel_3.Text = 'Storm intensification time [min]';
            app.IntensificationtimeSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.IntensificationtimeSlider_2.Limits = [0 15];
            app.IntensificationtimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.IntensificationtimeSlider_2.Tooltip = {''};
            app.IntensificationtimeSlider_2.Value = 5;
            app.IntensificationtimeField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.IntensificationtimeField_2.Limits = [0 15];
            app.IntensificationtimeField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.IntensificationtimeField_2.Tooltip = {''};
            app.IntensificationtimeField_2.Value = 5;
            app.TranslationalvelocityofstormmsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.TranslationalvelocityofstormmsLabel_2.Text = 'Translational velocity of storm [m/s]';
            app.utransSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.utransSlider_2.Limits = [0 30];
            app.utransSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.utransSlider_2.Value = 10;
            app.utransField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.utransField_2.Limits = [0 30];
            app.utransField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.utransField_2.Value = 10;
            app.TimedependentradiusofmaximumoutflowvelocitykmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.TimedependentradiusofmaximumoutflowvelocitykmLabel_2.Text = {'Radial rate of change of radius '; 'of maximum horizontal velocity [m/min]'};
            app.rtSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.rtSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.rtSlider_2.Value = 30;
            app.rtField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.rtField_2.Limits = [0 100];
            app.rtField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.rtField_2.Value = 30;
            app.LengthofstormintensificationminSliderLabel_4 = uilabel(app.VerticalProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_4.Text = 'Storm duration [min] ';
            app.TotaltimeSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.TotaltimeSlider_2.Limits = [0 30];
            app.TotaltimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.TotaltimeSlider_2.Value = 15;
            app.TotaltimeField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.TotaltimeField_2.Limits = [0 30];
            app.TotaltimeField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.TotaltimeField_2.Value = 15;
            app.RadiusofmaximumhorizontalvelocitykmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.RadiusofmaximumhorizontalvelocitykmLabel_2.Tooltip = {''};
            app.RadiusofmaximumhorizontalvelocitykmLabel_2.Text = {'Radius of maximum horizontal'; 'velocity [km]'};
            app.Image_4 = uiimage(app.VerticalProfileModelParametersPanel);
            app.Image_4.Tooltip = {'Note that this is the radius of maximum velocity when the intensity factor is equal to 1. '};
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.Image_5 = uiimage(app.VerticalProfileModelParametersPanel);
            app.Image_5.Tooltip = {'Default value is 2.'};
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.Image_6 = uiimage(app.VerticalProfileModelParametersPanel);
            app.Image_6.Tooltip = {'Storm intensification time is typically 5 minutes. Over 90% of storms reached maximum intensification within 10 minutes (Hjelmfelt 1988; Wilson et al. 1984).'};
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.LengthofstormintensificationminSliderLabel_7 = uilabel(app.VerticalProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_7.Text = 'Storm current time [min] ';
            app.TimeSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.TimeSlider_2.Limits = [0 30];
            app.TimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.TimeSlider_2.Value = 10;
            app.TimeField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.TimeField_2.Limits = [0 30];
            app.TimeField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.TimeField_2.Value = 10;
            app.Image_8 = uiimage(app.VerticalProfileModelParametersPanel);
            app.Image_8.Tooltip = {'The authors have proposed values of 0 m/min and 30 m/min in case studies using this model. '};
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.RadiallydependentheightofmaximumoutflowvelocitymLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.RadiallydependentheightofmaximumoutflowvelocitymLabel_2.Text = {'Radially dependent height of'; 'maximum outflow velocity [m]'};
            app.zrSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.zrSlider_2.Limits = [0 1000];
            app.zrSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.zrSlider_2.Value = 100;
            app.zrField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.zrField_2.Limits = [0 1000];
            app.zrField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.zrField_2.Value = 100;
            app.Panel_2 = uipanel(app.UIFigure);
            app.zmLabel_2 = uilabel(app.Panel_2);
            app.zmLabel_2.Text = 'Distance from downdraft center [km]';
            app.rSlider = uislider(app.Panel_2);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.rSlider.Value = 1;
            app.rField = uieditfield(app.Panel_2, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.rField.Value = 1;
            app.LengthofstormintensificationminSliderLabel_8 = uilabel(app.Panel_2);
            app.LengthofstormintensificationminSliderLabel_8.Text = 'Azimuth angle [degrees]';
            app.AngleSlider_2 = uislider(app.Panel_2);
            app.AngleSlider_2.Limits = [0 360];
            app.AngleSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.AngleField_2 = uieditfield(app.Panel_2, 'numeric');
            app.AngleField_2.Limits = [0 360];
            app.AngleField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.ExportPlotButton_3 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_2Pushed, true);
            app.ExportPlotButton_3.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_3 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton_2Pushed, true);
            app.ExportDataTableButton_3.Text = {'Export Data Table'; ''};
            app.Panel_3 = uipanel(app.UIFigure);
            app.FixedParametersCheckBox = uicheckbox(app.Panel_3);
            app.FixedParametersCheckBox.Text = 'Keep model parameters fixed';
            app.SchematicofSomeModelParametersPanel = uipanel(app.UIFigure);
            app.SchematicofSomeModelParametersPanel.Title = 'Schematic of Some Model Parameters';
            app.Image2 = uiimage(app.SchematicofSomeModelParametersPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'DownburstTranslationSchematic.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end