classdef DownburstModelsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectModelPanel             matlab.ui.container.Panel
        Vicroy1992Button             matlab.ui.control.Button
        OsegueraandBowles1988Button  matlab.ui.control.Button
        Xhelajetal2020Button         matlab.ui.control.Button
        Woodetal2001Button           matlab.ui.control.Button
        Verhoff1963Button            matlab.ui.control.Button
        MoeiniandRomanic2023Button   matlab.ui.control.Button
        HolmesandOliver2000Button    matlab.ui.control.Button
        Lietal2012Button             matlab.ui.control.Button
        Chayetal2006Button           matlab.ui.control.Button
        DownburstModelsSelectionMenuLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        OsegueraBowles % Oseguera & Bowles model app
        Vicroy % Vicroy model app
        Chay % Chay model app
        Li % Li model app
        HolmesOliver % Holmes & Oliver model app
        MoeiniRomanic % Moeini & Romanic model app
        Verhoff % Verhoff model app
        Wood % Wood model app
        Xhelaj % Xhelaj model app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OsegueraandBowles1988Button
        function OsegueraandBowles1988ButtonPushed(app, event)
            % Disable opening multiple windows
            app.OsegueraandBowles1988Button.Enable = 'off';

            % Open OB model app
            app.OsegueraBowles = OsegueraBowles(app);
        end

        % Button pushed function: Vicroy1992Button
        function Vicroy1992ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Vicroy1992Button.Enable = 'off';

            % Open OBV model app
            app.Vicroy = Vicroy(app);
        end

        % Button pushed function: Chayetal2006Button
        function Chayetal2006ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Chayetal2006Button.Enable = 'off';

            % Open OBV: Chay model app
            app.Chay = Chay(app);
        end

        % Button pushed function: Lietal2012Button
        function Lietal2012ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Lietal2012Button.Enable = 'off';

            % Open OBV: Li model app
            app.Li = Li(app);  
        end

        % Button pushed function: HolmesandOliver2000Button
        function HolmesandOliver2000ButtonPushed(app, event)
            % Disable opening multiple windows
            app.HolmesandOliver2000Button.Enable = 'off';

            % Open Holmes & Oliver model app
            app.HolmesOliver = HolmesOliver(app);  
        end

        % Button pushed function: MoeiniandRomanic2023Button
        function MoeiniandRomanic2023ButtonPushed(app, event)
            % Disable opening multiple windows
            app.MoeiniandRomanic2023Button.Enable = 'off';

            % Open Moeini & Romanic model app
            app.MoeiniRomanic = MoeiniRomanic(app);  
        end

        % Button pushed function: Verhoff1963Button
        function Verhoff1963ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Verhoff1963Button.Enable = 'off';

            % Open Verhoff model app
            app.Verhoff = Verhoff(app);  
        end

        % Button pushed function: Woodetal2001Button
        function Woodetal2001ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Woodetal2001Button.Enable = 'off';

            % Open Wood model app
            app.Wood = Wood(app); 
        end

        % Button pushed function: Xhelajetal2020Button
        function Xhelajetal2020ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Xhelajetal2020Button.Enable = 'off';

            % Open Wood model app
            app.Xhelaj = Xhelaj(app); 
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            % Close all windows when selection menu is closed
            delete(app.OsegueraBowles)
            delete(app.Vicroy)
            delete(app.Chay)
            delete(app.Li)
            delete(app.HolmesOliver)
            delete(app.MoeiniRomanic)
            delete(app.Verhoff)
            delete(app.Wood)
            delete(app.Xhelaj)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.DownburstModelsSelectionMenuLabel = uilabel(app.UIFigure);
            app.DownburstModelsSelectionMenuLabel.Text = 'Downburst Models Selection Menu';
            app.SelectModelPanel = uipanel(app.UIFigure);
            app.SelectModelPanel.Title = 'Select Model:';
            app.Chayetal2006Button = uibutton(app.SelectModelPanel, 'push');
            app.Chayetal2006Button.ButtonPushedFcn = createCallbackFcn(app, @Chayetal2006ButtonPushed, true);
            app.Chayetal2006Button.Text = 'Chay et al. (2006)';
            app.Lietal2012Button = uibutton(app.SelectModelPanel, 'push');
            app.Lietal2012Button.ButtonPushedFcn = createCallbackFcn(app, @Lietal2012ButtonPushed, true);
            app.Lietal2012Button.Text = 'Li et al. (2012)';
            app.HolmesandOliver2000Button = uibutton(app.SelectModelPanel, 'push');
            app.HolmesandOliver2000Button.ButtonPushedFcn = createCallbackFcn(app, @HolmesandOliver2000ButtonPushed, true);
            app.HolmesandOliver2000Button.Text = 'Holmes and Oliver (2000)';
            app.MoeiniandRomanic2023Button = uibutton(app.SelectModelPanel, 'push');
            app.MoeiniandRomanic2023Button.ButtonPushedFcn = createCallbackFcn(app, @MoeiniandRomanic2023ButtonPushed, true);
            app.MoeiniandRomanic2023Button.Text = 'Moeini and Romanic (2023)';
            app.Verhoff1963Button = uibutton(app.SelectModelPanel, 'push');
            app.Verhoff1963Button.ButtonPushedFcn = createCallbackFcn(app, @Verhoff1963ButtonPushed, true);
            app.Verhoff1963Button.Text = 'Verhoff (1963)';
            app.Woodetal2001Button = uibutton(app.SelectModelPanel, 'push');
            app.Woodetal2001Button.ButtonPushedFcn = createCallbackFcn(app, @Woodetal2001ButtonPushed, true);
            app.Woodetal2001Button.Text = 'Wood et al. (2001)';
            app.Xhelajetal2020Button = uibutton(app.SelectModelPanel, 'push');
            app.Xhelajetal2020Button.ButtonPushedFcn = createCallbackFcn(app, @Xhelajetal2020ButtonPushed, true);
            app.Xhelajetal2020Button.Text = 'Xhelaj et al. (2020)';
            app.OsegueraandBowles1988Button = uibutton(app.SelectModelPanel, 'push');
            app.OsegueraandBowles1988Button.ButtonPushedFcn = createCallbackFcn(app, @OsegueraandBowles1988ButtonPushed, true);
            app.OsegueraandBowles1988Button.Text = 'Oseguera and Bowles (1988)';
            app.Vicroy1992Button = uibutton(app.SelectModelPanel, 'push');
            app.Vicroy1992Button.ButtonPushedFcn = createCallbackFcn(app, @Vicroy1992ButtonPushed, true);
            app.Vicroy1992Button.Text = 'Vicroy (1992)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DownburstModelsGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end