classdef HolmesOliver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Hyperlink                      matlab.ui.control.Hyperlink
        ExportDataTableButton          matlab.ui.control.Button
        ExportPlotButton               matlab.ui.control.Button
        ModelParametersPanel           matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        timeconstantField              matlab.ui.control.NumericEditField
        timeconstantSlider             matlab.ui.control.Slider
        TimeconstantminLabel           matlab.ui.control.Label
        timeelapsedField               matlab.ui.control.NumericEditField
        timeelapsedSlider              matlab.ui.control.Slider
        TimefrompeakintensityminLabel  matlab.ui.control.Label
        radiallengthField              matlab.ui.control.NumericEditField
        radiallengthSlider             matlab.ui.control.Slider
        RadiallengthscalekmLabel       matlab.ui.control.Label
        rmaxField                      matlab.ui.control.NumericEditField
        MaxhorizontalvelField          matlab.ui.control.NumericEditField
        MaxhorizontalvelSlider         matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel  matlab.ui.control.Label
        rmaxSlider                     matlab.ui.control.Slider
        RadiusofmaximumhorizontalvelocitykmLabel  matlab.ui.control.Label
        HolmesandOliver2000Label       matlab.ui.control.Label
        HorizontalAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)

        function u_r = compute_ur(app, r, ur_max, r_max, R, t , T)
            % Horizontal downburst velocity (Equation 3)
            u_r = zeros(length(r), 1);
            for i = 1:length(r)
                if r(i) < r_max
                    u_r(i) = ur_max * (r(i)/r_max) * exp(-t/T);
                else
                    u_r(i) = ur_max * exp(-((r(i)-r_max)/R)^2) * exp(-t/T);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller6)
            % Store selection menu app object
            app.CallingApp = caller6;

            % Define parameters for radial profile
            r = 0:1:10000;
            ur_max = app.MaxhorizontalvelField.Value;
            r_max = app.rmaxField.Value * 1000;
            R = app.radiallengthField.Value * 1000;
            t = app.timeelapsedField.Value; % Time since peak intensity
            T = app.timeconstantField.Value; % Time constant

            % Plot radial profile 
            plot(app.HorizontalAxes, r, compute_ur(app, r, ur_max, r_max, R, t, T), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end
        end

        % Value changed function: MaxhorizontalvelField, 
        % ...and 4 other components
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            ur_max = app.MaxhorizontalvelField.Value;
            r_max = app.rmaxField.Value * 1000;
            R = app.radiallengthField.Value * 1000;
            t = app.timeelapsedField.Value;
            T = app.timeconstantField.Value;

            % Plot radial velocity
            plot(app.HorizontalAxes, r, compute_ur(app, r, ur_max, r_max, R, t, T), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % Update sliders to match field values
            if app.MaxhorizontalvelField.Value ~= app.MaxhorizontalvelSlider.Value
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelField.Value;
            else 
            end
            if app.rmaxField.Value ~= app.rmaxSlider.Value
                app.rmaxSlider.Value = app.rmaxField.Value;
            else
            end
            if app.radiallengthField.Value ~= app.radiallengthSlider.Value
                app.radiallengthSlider.Value = app.radiallengthField.Value;
            else
            end
            if app.timeelapsedField.Value ~= app.timeelapsedSlider.Value
                app.timeelapsedSlider.Value = app.timeelapsedField.Value;
            else
            end
            if app.timeconstantField.Value ~= app.timeconstantSlider.Value
                app.timeconstantSlider.Value = app.timeconstantField.Value;
            else
            end
        end

        % Value changed function: MaxhorizontalvelSlider, 
        % ...and 4 other components
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            ur_max = app.MaxhorizontalvelSlider.Value;
            r_max = app.rmaxSlider.Value * 1000;
            R = app.radiallengthSlider.Value * 1000;
            t = app.timeelapsedSlider.Value;
            T = app.timeconstantSlider.Value;

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_ur(app, r, ur_max, r_max, R, t, T), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end
            
            % Update field values to match sliders
            if app.MaxhorizontalvelSlider.Value ~= app.MaxhorizontalvelField.Value
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelSlider.Value;
            else
            end  
            if app.rmaxSlider.Value ~= app.rmaxField.Value
                app.rmaxField.Value = app.rmaxSlider.Value;            
            else
            end
            if app.radiallengthSlider.Value ~= app.radiallengthField.Value
                app.radiallengthField.Value = app.radiallengthSlider.Value;
            else
            end
            if app.timeelapsedSlider.Value ~= app.timeelapsedField.Value
                app.timeelapsedField.Value = app.timeelapsedSlider.Value;
            else
            end
            if app.timeconstantSlider.Value ~= app.timeconstantField.Value
                app.timeconstantField.Value = app.timeconstantSlider.Value;
            else
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            ur_max = app.MaxhorizontalvelField.Value;
            r_max = app.rmaxField.Value * 1000;
            R = app.radiallengthField.Value * 1000;
            t = app.timeelapsedField.Value;
            T = app.timeconstantField.Value;

            % Put x axis values into an array
            r = [0:1:10000];
            % Compute velocities and insert into arrays
            m = [compute_ur(app, r, ur_max, r_max, R, t, T)];

            % Arrange arrays into a table
            ar = [r; m'];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2"], ["Distance from downdraft center [m]", "Velocity [m/s]",]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Close request function: UIFigure
        function HolmesOliverCloseRequest(app, event)
            % Reenable Holmes & Oliver button
            app.CallingApp.HolmesandOliver2000Button.Enable = 'on';

            % Close Holmes & Oliver window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @HolmesOliverCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.HolmesandOliver2000Label = uilabel(app.UIFigure);
            app.HolmesandOliver2000Label.Text = 'Holmes and Oliver (2000)';
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.RadiusofmaximumhorizontalvelocitykmLabel = uilabel(app.ModelParametersPanel);
            app.RadiusofmaximumhorizontalvelocitykmLabel.Text = 'Radius of maximum horizontal velocity [km]';
            app.rmaxSlider = uislider(app.ModelParametersPanel);
            app.rmaxSlider.Limits = [0 5];
            app.rmaxSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rmaxSlider.Value = 1;
            app.MaximumhorizontalvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider = uislider(app.ModelParametersPanel);
            app.MaxhorizontalvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxhorizontalvelSlider.Value = 40;
            app.MaxhorizontalvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField.Limits = [0 100];
            app.MaxhorizontalvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxhorizontalvelField.Value = 40;
            app.rmaxField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.rmaxField.Limits = [0 5];
            app.rmaxField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rmaxField.Value = 1;
            app.RadiallengthscalekmLabel = uilabel(app.ModelParametersPanel);
            app.RadiallengthscalekmLabel.Tooltip = {''};
            app.RadiallengthscalekmLabel.Text = 'Radial length scale [km]';
            app.radiallengthSlider = uislider(app.ModelParametersPanel);
            app.radiallengthSlider.Limits = [0 10];
            app.radiallengthSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.radiallengthSlider.Tooltip = {''};
            app.radiallengthSlider.Value = 0.5;
            app.radiallengthField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.radiallengthField.Limits = [0 10];
            app.radiallengthField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.radiallengthField.Tooltip = {''};
            app.radiallengthField.Value = 0.5;
            app.TimefrompeakintensityminLabel = uilabel(app.ModelParametersPanel);
            app.TimefrompeakintensityminLabel.Text = 'Time from peak intensity [min]';
            app.timeelapsedSlider = uislider(app.ModelParametersPanel);
            app.timeelapsedSlider.Limits = [0 60];
            app.timeelapsedSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.timeelapsedSlider.Value = 15;
            app.timeelapsedField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.timeelapsedField.Limits = [0 60];
            app.timeelapsedField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.timeelapsedField.Value = 15;
            app.TimeconstantminLabel = uilabel(app.ModelParametersPanel);
            app.TimeconstantminLabel.Text = 'Time constant [min]';
            app.timeconstantSlider = uislider(app.ModelParametersPanel);
            app.timeconstantSlider.Limits = [0 60];
            app.timeconstantSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.timeconstantSlider.Value = 15;
            app.timeconstantField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.timeconstantField.Limits = [0 60];
            app.timeconstantField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.timeconstantField.Value = 15;
            app.Image = uiimage(app.ModelParametersPanel);
            app.Image.Tooltip = {'Default value is 0.5 *  radius of maximum horizontal velocity. '};
            app.Image.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1016/S0141-0296(99)00058-9';
            app.Hyperlink.Text = 'Corresponding Paper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HolmesOliver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end