classdef Li < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlottingOptionsPanel_2          matlab.ui.container.Panel
        PlotverticalvelocityCheckBox    matlab.ui.control.CheckBox
        PlothorizontalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        Hyperlink                       matlab.ui.control.Hyperlink
        Panel                           matlab.ui.container.Panel
        zField                          matlab.ui.control.NumericEditField
        zSlider                         matlab.ui.control.Slider
        zmLabel                         matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        FixedParametersCheckBox         matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        rSlider                         matlab.ui.control.Slider
        rField                          matlab.ui.control.NumericEditField
        zmLabel_2                       matlab.ui.control.Label
        ExportDataTableButton_2         matlab.ui.control.Button
        ExportPlotButton_2              matlab.ui.control.Button
        VerticalProfileModelParametersPanel  matlab.ui.container.Panel
        maxzField_2                     matlab.ui.control.NumericEditField
        maxzSlider_2                    matlab.ui.control.Slider
        HeightofmaximumhorizontalvelocitymLabel_2  matlab.ui.control.Label
        maxrField_2                     matlab.ui.control.NumericEditField
        maxrSlider_2                    matlab.ui.control.Slider
        RadiusofmaximumhorizontalvelocitykmLabel_2  matlab.ui.control.Label
        MaxhorizontalvelField_2         matlab.ui.control.NumericEditField
        MaxhorizontalvelSlider_2        matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel_2  matlab.ui.control.Label
        ExportDataTableButton           matlab.ui.control.Button
        ExportPlotButton                matlab.ui.control.Button
        RadialProfileModelParametersPanel  matlab.ui.container.Panel
        maxzField                       matlab.ui.control.NumericEditField
        maxzSlider                      matlab.ui.control.Slider
        HeightofmaximumhorizontalvelocitymLabel  matlab.ui.control.Label
        maxrField                       matlab.ui.control.NumericEditField
        maxrSlider                      matlab.ui.control.Slider
        RadiusofmaximumhorizontalvelocitykmLabel  matlab.ui.control.Label
        MaxhorizontalvelField           matlab.ui.control.NumericEditField
        MaxhorizontalvelSlider          matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel  matlab.ui.control.Label
        Lietal2012Label                 matlab.ui.control.Label
        VerticalAxes                    matlab.ui.control.UIAxes
        HorizontalAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)
        
        function ur = compute_ur(app, u_mm, pn, fn)
            % Horizontal downburst velocity (Equation 35)
            ur = u_mm .* pn .* fn;
        end
        
        function w = compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs)
            % Vertical downburst velocity (Equation 36)
            w = u_mm .* phi_r .* w_rs .* phi_z .* w_vs;
        end

        function u = compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs)
            % Horizontal downburst velocity
            ur = u_mm .* pn .* fn;
        
            % Vertical downburst velocity
            w = u_mm .* phi_r .* w_rs .* phi_z .* w_vs;

            % Downburst velocity magnitude
            u = sqrt(ur.^2 + w.^2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller5)
            % Store selection menu app object
            app.CallingApp = caller5;

            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlothorizontalvelocityCheckBox.Value = 1;
            app.PlotverticalvelocityCheckBox.Value = 1;
            app.FixedParametersCheckBox.Value = 1;
            
            % Define parameters for radial profile
            r = 0:1:10000;
            u_mm = app.MaxhorizontalvelField.Value;
            r_max = app.maxrField.Value * 1000;
            z_max = app.maxzField.Value;
            z = app.zField.Value;
            gam = 0.261; % Gamma
            pn = (z/z_max)^gam * exp(gam*(1-(z/z_max))); % Equation 5
            R_c = 0.599*r_max; 
            bet = 1.287; % Beta
            fn = zeros(1, length(r)); % Equation 9
            for i = 1:length(r)
                if r(i) <= r_max
                    fn(i) = r(i)./r_max;
                else
                    fn(i) = exp(-((r(i)-r_max)./R_c).^bet);
                end
            end
            phi_r = 2/r_max; % Equation 19
            w_rs = zeros(1, length(r)); % Equation 29
            for i = 1:length(r)
                if r(i) <= r_max
                    w_rs(i) = 1;
                else
                    w_rs(i) = (exp(-((r(i)-r_max)/R_c).^bet)./(2*r(i))) - ((bet * (r(i)-r_max)^(bet-1) * exp(-((r(i)-r_max)/R_c)^bet))/(2*R_c^2));
                end
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max)); % Equation 5 
            phi_z = -integral(f, 0, inf); % Equation 18
            w_vs = integral(f, 0, z)/integral(f, 0, inf); % Equation 27

            % Plot radial profile horizontal and vertical velocities as well as magnitude            
            plot(app.HorizontalAxes, r, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), 'k', r, compute_ur(app, u_mm, pn, fn), 'r', r, compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % Define parameters for vertical profile
            z = 0:1:1000;
            u_mm = app.MaxhorizontalvelField_2.Value;
            r_max = app.maxrField_2.Value * 1000;
            z_max = app.maxzField_2.Value;
            r = app.rField.Value * 1000;
            gam = 0.261;
            pn = (z./z_max).^gam .* exp(gam*(1-(z./z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            if r < r_max
                fn = r/r_max;
            else
                fn = exp(-((r-r_max)/R_c)^bet);
            end
            phi_r = 2/r_max;
            if r <= r_max
                w_rs = 1;
            else
                w_rs = (exp(-((r-r_max)/R_c).^bet)./(2*r)) - ((bet * (r-r_max)^(bet-1) * exp(-((r-r_max)/R_c)^bet))/(2*R_c^2));
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max)); 
            phi_z = -integral(f, 0, inf);
            wvs_num = zeros(1, length(z));
            for i = 1:length(z)
                wvs_num(i) = integral(f, 0, z(i));
            end
            wvs_den = integral(f, 0, inf);
            w_vs = wvs_num./wvs_den;

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), z, 'k', compute_ur(app, u_mm, pn, fn), z, 'r', compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
        end

        % Value changed function: MaxhorizontalvelField, maxrField, 
        % ...and 2 other components
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_mm = app.MaxhorizontalvelField.Value;
            r_max = app.maxrField.Value * 1000;
            z_max = app.maxzField.Value;
            z = app.zField.Value;
            gam = 0.261;
            pn = (z/z_max)^gam * exp(gam*(1-(z/z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            fn = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_max
                    fn(i) = r(i)./r_max;
                else
                    fn(i) = exp(-((r(i)-r_max)./R_c).^bet);
                end
            end
            phi_r = (2/r_max);
            w_rs = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_max
                    w_rs(i) = 1;
                else
                    w_rs(i) = (exp(-((r(i)-r_max)/R_c).^bet)./(2*r(i))) - ((bet * (r(i)-r_max)^(bet-1) * exp(-((r(i)-r_max)/R_c)^bet))/(2*R_c^2));
                end
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
            phi_z = -integral(f, 0, inf);
            w_vs = integral(f, 0, z)/integral(f, 0, inf);

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), 'k', r, compute_ur(app, u_mm, pn, fn), 'r', r, compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update sliders to match field values
            if app.MaxhorizontalvelField.Value ~= app.MaxhorizontalvelSlider.Value
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelField.Value;
            else 
            end
            if app.maxrField.Value ~= app.maxrSlider.Value
                app.maxrSlider.Value = app.maxrField.Value;
            else
            end
            if app.maxzField.Value ~= app.maxzSlider.Value
                app.maxzSlider.Value = app.maxzField.Value;
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            else 
            end

            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelField.Value;
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelSlider.Value;
                app.maxrField_2.Value = app.maxrField.Value;
                app.maxrSlider_2.Value = app.maxrSlider.Value;
                app.maxzField_2.Value = app.maxzField.Value;
                app.maxzSlider_2.Value = app.maxzSlider.Value;

                % Define parameters for vertical profile
                z = 0:1:1000;
                u_mm = app.MaxhorizontalvelField_2.Value;
                r_max = app.maxrField_2.Value * 1000;
                z_max = app.maxzField_2.Value;
                r = app.rField.Value * 1000;
                gam = 0.261;
                pn = (z./z_max).^gam .* exp(gam*(1-(z./z_max)));
                R_c = 0.599*r_max; 
                bet = 1.287;
                if r < r_max
                    fn = r/r_max;
                else
                    fn = exp(-((r-r_max)/R_c)^bet);
                end
                phi_r = (2/r_max);
                if r <= r_max
                    w_rs = 1;
                else
                    w_rs = (exp(-((r-r_max)/R_c).^bet)./(2*r)) - ((bet * (r-r_max)^(bet-1) * exp(-((r-r_max)/R_c)^bet))/(2*R_c^2));
                end
                f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
                phi_z = -integral(f, 0, inf);
                wvs_num = zeros(1, length(z));
                for i = 1:length(z)
                    wvs_num(i) = integral(f, 0, z(i));
                end
                wvs_den = integral(f, 0, inf);
                w_vs = wvs_num./wvs_den;
    
                % Plot vertical profile
                plot(app.VerticalAxes, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), z, 'k', compute_ur(app, u_mm, pn, fn), z, 'r', compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: MaxhorizontalvelSlider, maxrSlider, 
        % ...and 2 other components
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_mm = app.MaxhorizontalvelSlider.Value;
            r_max = app.maxrSlider.Value * 1000;
            z_max = app.maxzSlider.Value;
            z = app.zSlider.Value;
            gam = 0.261;
            pn = (z/z_max)^gam * exp(gam*(1-(z/z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            fn = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_max
                    fn(i) = r(i)./r_max;
                else
                    fn(i) = exp(-((r(i)-r_max)./R_c).^bet);
                end
            end
            phi_r = (2/r_max);
            w_rs = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_max
                    w_rs(i) = 1;
                else
                    w_rs(i) = (exp(-((r(i)-r_max)/R_c).^bet)./(2*r(i))) - ((bet * (r(i)-r_max)^(bet-1) * exp(-((r(i)-r_max)/R_c)^bet))/(2*R_c^2));
                end
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
            phi_z = -integral(f, 0, inf);
            w_vs = integral(f, 0, z)/integral(f, 0, inf);

            % Plot radial profile 
            plot(app.HorizontalAxes, r, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), 'k', r, compute_ur(app, u_mm, pn, fn), 'r', r, compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxhorizontalvelSlider.Value ~= app.MaxhorizontalvelField.Value
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelSlider.Value;
            else
            end  
            if app.maxrSlider.Value ~= app.maxrField.Value
                app.maxrField.Value = app.maxrSlider.Value;
            else
            end
            if app.maxzSlider.Value ~= app.maxzField.Value
                app.maxzField.Value = app.maxzSlider.Value;
            end
            if app.zSlider.Value ~= app.zField.Value
                app.zField.Value = app.zSlider.Value;
            else
            end

            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelField.Value;
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelSlider.Value;
                app.maxrField_2.Value = app.maxrField.Value;
                app.maxrSlider_2.Value = app.maxrSlider.Value;
                app.maxzField_2.Value = app.maxzField.Value;
                app.maxzSlider_2.Value = app.maxzSlider.Value;

                % Define parameters for vertical profile
                z = 0:1:1000;
                u_mm = app.MaxhorizontalvelField_2.Value;
                r_max = app.maxrField_2.Value * 1000;
                z_max = app.maxzField_2.Value;
                r = app.rField.Value * 1000;
                gam = 0.261;
                pn = (z./z_max).^gam .* exp(gam*(1-(z./z_max)));
                R_c = 0.599*r_max; 
                bet = 1.287;
                if r < r_max
                    fn = r/r_max;
                else
                    fn = exp(-((r-r_max)/R_c)^bet);
                end
                phi_r = (2/r_max);
                if r <= r_max
                    w_rs = 1;
                else
                    w_rs = (exp(-((r-r_max)/R_c).^bet)./(2*r)) - ((bet * (r-r_max)^(bet-1) * exp(-((r-r_max)/R_c)^bet))/(2*R_c^2));
                end
                f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
                phi_z = -integral(f, 0, inf);
                wvs_num = zeros(1, length(z));
                for i = 1:length(z)
                    wvs_num(i) = integral(f, 0, z(i));
                end
                wvs_den = integral(f, 0, inf);
                w_vs = wvs_num./wvs_den;

                % Plot vertical profile
                plot(app.VerticalAxes, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), z, 'k', compute_ur(app, u_mm, pn, fn), z, 'r', compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: MaxhorizontalvelField_2, maxrField_2, 
        % ...and 2 other components
        function FieldValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            u_mm = app.MaxhorizontalvelField_2.Value;
            r_max = app.maxrField_2.Value * 1000;
            z_max = app.maxzField_2.Value;
            r = app.rField.Value * 1000;
            gam = 0.261;
            pn = (z./z_max).^gam .* exp(gam*(1-(z./z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            if r < r_max
                fn = r/r_max;
            else
                fn = exp(-((r-r_max)/R_c)^bet);
            end
            phi_r = (2/r_max);
            if r <= r_max
                w_rs = 1;
            else
                w_rs = (exp(-((r-r_max)/R_c).^bet)./(2*r)) - ((bet * (r-r_max)^(bet-1) * exp(-((r-r_max)/R_c)^bet))/(2*R_c^2));
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
            phi_z = -integral(f, 0, inf);
            wvs_num = zeros(1, length(z));
            for i = 1:length(z)
                wvs_num(i) = integral(f, 0, z(i));
            end
            wvs_den = integral(f, 0, inf);
            w_vs = wvs_num./wvs_den;

            % Plot vertical profile
            plot(app.VerticalAxes, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), z, 'k', compute_ur(app, u_mm, pn, fn), z, 'r', compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
            
            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxhorizontalvelField_2.Value ~= app.MaxhorizontalvelSlider_2.Value
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelField_2.Value;
            else
            end
            if app.maxrField_2.Value ~= app.maxrSlider_2.Value
                app.maxrSlider_2.Value = app.maxrField_2.Value;
            else
            end
            if app.maxzField_2.Value ~= app.maxzSlider_2.Value
                app.maxzSlider_2.Value = app.maxzField_2.Value;
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            else
            end
            
            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelField_2.Value;
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelSlider_2.Value;
                app.maxrField.Value = app.maxrField_2.Value;
                app.maxrSlider.Value = app.maxrSlider_2.Value;
                app.maxzField.Value = app.maxzField_2.Value;
                app.maxzSlider.Value = app.maxzSlider_2.Value;

                % Define radial profile parameters
                r = 0:1:10000;
                u_mm = app.MaxhorizontalvelField.Value;
                r_max = app.maxrField.Value * 1000;
                z_max = app.maxzField.Value;
                z = app.zField.Value;
                gam = 0.261;
                pn = (z/z_max)^gam * exp(gam*(1-(z/z_max)));
                R_c = 0.599*r_max; 
                bet = 1.287;
                fn = zeros(1, length(r));
                for i = 1:length(r)
                    if r(i) <= r_max
                        fn(i) = r(i)./r_max;
                    else
                        fn(i) = exp(-((r(i)-r_max)./R_c).^bet);
                    end
                end
                phi_r = (2/r_max);
                w_rs = zeros(1, length(r));
                for i = 1:length(r)
                    if r(i) <= r_max
                        w_rs(i) = 1;
                    else
                        w_rs(i) = (exp(-((r(i)-r_max)/R_c).^bet)./(2*r(i))) - ((bet * (r(i)-r_max)^(bet-1) * exp(-((r(i)-r_max)/R_c)^bet))/(2*R_c^2));
                    end
                end
                f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
                phi_z = -integral(f, 0, inf);
                w_vs = integral(f, 0, z)/integral(f, 0, inf);

                % Plot radial profile
                plot(app.HorizontalAxes, r, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), 'k', r, compute_ur(app, u_mm, pn, fn), 'r', r, compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');

                % Adjust axis for radial profile
                r_lim = 2 * r_max;
                if (r_lim+2000) > 10000
                    xlim(app.HorizontalAxes, [0, 10000]);
                else
                    xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
                end

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: MaxhorizontalvelSlider_2, maxrSlider_2, 
        % ...and 2 other components
        function SliderValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            u_mm = app.MaxhorizontalvelSlider_2.Value;
            r_max = app.maxrSlider_2.Value * 1000;
            z_max = app.maxzSlider_2.Value;
            r = app.rSlider.Value * 1000;
            gam = 0.261;
            pn = (z./z_max).^gam .* exp(gam*(1-(z./z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            if r < r_max
                fn = r/r_max;
            else
                fn = exp(-((r-r_max)/R_c)^bet);
            end
            phi_r = (2/r_max);
            if r <= r_max
                w_rs = 1;
            else
                w_rs = (exp(-((r-r_max)/R_c).^bet)./(2*r)) - ((bet * (r-r_max)^(bet-1) * exp(-((r-r_max)/R_c)^bet))/(2*R_c^2));
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
            phi_z = -integral(f, 0, inf);
            wvs_num = zeros(1, length(z));
            for i = 1:length(z)
                wvs_num(i) = integral(f, 0, z(i));
            end
            wvs_den = integral(f, 0, inf);
            w_vs = wvs_num./wvs_den;

            % Plot vertical profile
            plot(app.VerticalAxes, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), z, 'k', compute_ur(app, u_mm, pn, fn), z, 'r', compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxhorizontalvelSlider_2.Value ~= app.MaxhorizontalvelField_2.Value
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelSlider_2.Value;
            else
            end  
            if app.maxrSlider_2.Value ~= app.maxrField_2.Value
                app.maxrField_2.Value = app.maxrSlider_2.Value;
            else
            end
            if app.maxzSlider_2.Value ~= app.maxzField_2.Value
                app.maxzField_2.Value = app.maxzSlider_2.Value;
            end
            if app.rSlider.Value ~= app.rField.Value
                app.rField.Value = app.rSlider.Value;
            else
            end

            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelField_2.Value;
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelSlider_2.Value;
                app.maxrField.Value = app.maxrField_2.Value;
                app.maxrSlider.Value = app.maxrSlider_2.Value;
                app.maxzField.Value = app.maxzField_2.Value;
                app.maxzSlider.Value = app.maxzSlider_2.Value;

                % Define radial profile parameters
                r = 0:1:10000;
                u_mm = app.MaxhorizontalvelField.Value;
                r_max = app.maxrField.Value * 1000;
                z_max = app.maxzField.Value;
                z = app.zField.Value;
                gam = 0.261;
                pn = (z/z_max)^gam * exp(gam*(1-(z/z_max)));
                R_c = 0.599*r_max; 
                bet = 1.287;
                fn = zeros(1, length(r));
                for i = 1:length(r)
                    if r(i) <= r_max
                        fn(i) = r(i)./r_max;
                    else
                        fn(i) = exp(-((r(i)-r_max)./R_c).^bet);
                    end
                end
                phi_r = (2/r_max);
                w_rs = zeros(1, length(r));
                for i = 1:length(r)
                    if r(i) <= r_max
                        w_rs(i) = 1;
                    else
                        w_rs(i) = (exp(-((r(i)-r_max)/R_c).^bet)./(2*r(i))) - ((bet * (r(i)-r_max)^(bet-1) * exp(-((r(i)-r_max)/R_c)^bet))/(2*R_c^2));
                    end
                end
                f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
                phi_z = -integral(f, 0, inf);
                w_vs = integral(f, 0, z)/integral(f, 0, inf);

                % Plot radial profile
                plot(app.HorizontalAxes, r, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), 'k', r, compute_ur(app, u_mm, pn, fn), 'r', r, compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');

                % Adjust axis for radial profile
                r_lim = 2 * r_max;
                if (r_lim+2000) > 10000
                    xlim(app.HorizontalAxes, [0, 10000]);
                else
                    xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
                end

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: PlothorizontalvelocityCheckBox, 
        % ...and 2 other components
        function CheckBoxValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_mm = app.MaxhorizontalvelField.Value;
            r_max = app.maxrField.Value * 1000;
            z_max = app.maxzField.Value;
            z = app.zField.Value;
            gam = 0.261;
            pn = (z/z_max)^gam * exp(gam*(1-(z/z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            fn = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_max
                    fn(i) = r(i)./r_max;
                else
                    fn(i) = exp(-((r(i)-r_max)./R_c).^bet);
                end
            end
            phi_r = (2/r_max);
            w_rs = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_max
                    w_rs(i) = 1;
                else
                    w_rs(i) = (exp(-((r(i)-r_max)/R_c).^bet)./(2*r(i))) - ((bet * (r(i)-r_max)^(bet-1) * exp(-((r(i)-r_max)/R_c)^bet))/(2*R_c^2));
                end
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
            phi_z = -integral(f, 0, inf);
            w_vs = integral(f, 0, z)/integral(f, 0, inf);

            % Plot radial profile horizontal and vertical velocities as well as magnitude            
            plot(app.HorizontalAxes, r, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), 'k', r, compute_ur(app, u_mm, pn, fn), 'r', r, compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_lim = 2 * r_max;
            if (r_lim+2000) > 10000
                xlim(app.HorizontalAxes, [0, 10000]);
            else
                xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
            end

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end


            % Define parameters for vertical profile
            z = 0:1:1000;
            u_mm = app.MaxhorizontalvelField_2.Value;
            r_max = app.maxrField_2.Value * 1000;
            z_max = app.maxzField_2.Value;
            r = app.rField.Value * 1000;
            gam = 0.261;
            pn = (z./z_max).^gam .* exp(gam*(1-(z./z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            if r < r_max
                fn = r/r_max;
            else
                fn = exp(-((r-r_max)/R_c)^bet);
            end
            phi_r = (2/r_max);
            if r <= r_max
                w_rs = 1;
            else
                w_rs = (exp(-((r-r_max)/R_c).^bet)./(2*r)) - ((bet * (r-r_max)^(bet-1) * exp(-((r-r_max)/R_c)^bet))/(2*R_c^2));
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
            phi_z = -integral(f, 0, inf);
            wvs_num = zeros(1, length(z));
            for i = 1:length(z)
                wvs_num(i) = integral(f, 0, z(i));
            end
            wvs_den = integral(f, 0, inf);
            w_vs = wvs_num./wvs_den;

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs), z, 'k', compute_ur(app, u_mm, pn, fn), z, 'r', compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end
            
            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_mm = app.MaxhorizontalvelField.Value;
            r_max = app.maxrField.Value * 1000;
            z_max = app.maxzField.Value;
            z = app.zField.Value;
            gam = 0.261;
            pn = (z/z_max)^gam * exp(gam*(1-(z/z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            fn = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_max
                    fn(i) = r(i)./r_max;
                else
                    fn(i) = exp(-((r(i)-r_max)./R_c).^bet);
                end
            end
            phi_r = (2/r_max);
            w_rs = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_max
                    w_rs(i) = 1;
                else
                    w_rs(i) = (exp(-((r(i)-r_max)/R_c).^bet)./(2*r(i))) - ((bet * (r(i)-r_max)^(bet-1) * exp(-((r(i)-r_max)/R_c)^bet))/(2*R_c^2));
                end
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
            phi_z = -integral(f, 0, inf);
            w_vs = integral(f, 0, z)/integral(f, 0, inf);

            % Put x axis values into an array
            r = [0:1:10000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs)];
            h = [compute_ur(app, u_mm, pn, fn)];
            v = [compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs)];

            % Arrange arrays into a table
            ar = [r; m; h; v];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2", "Var3", "Var4"], ["Distance from downdraft center [m]","Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButton_2Pushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.VerticalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.VerticalAxes.XLim;
            figAxes.YLim = app.VerticalAxes.YLim;
            figAxes.ZLim = app.VerticalAxes.ZLim;
            figAxes.DataAspectRatio = app.VerticalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Vertical Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButton_2Pushed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            u_mm = app.MaxhorizontalvelField_2.Value;
            r_max = app.maxrField_2.Value * 1000;
            z_max = app.maxzField_2.Value;
            r = app.rField.Value * 1000;
            gam = 0.261;
            pn = (z./z_max).^gam .* exp(gam*(1-(z./z_max)));
            R_c = 0.599*r_max; 
            bet = 1.287;
            if r < r_max
                fn = r/r_max;
            else
                fn = exp(-((r-r_max)/R_c)^bet);
            end
            phi_r = (2/r_max);
            if r <= r_max
                w_rs = 1;
            else
                w_rs = (exp(-((r-r_max)/R_c).^bet)./(2*r)) - ((bet * (r-r_max)^(bet-1) * exp(-((r-r_max)/R_c)^bet))/(2*R_c^2));
            end
            f = @(x) (x./z_max).^gam .* exp(gam * (1-x./z_max));
            phi_z = -integral(f, 0, inf);
            wvs_num = zeros(1, length(z));
            for i = 1:length(z)
                wvs_num(i) = integral(f, 0, z(i));
            end
            wvs_den = integral(f, 0, inf);
            w_vs = wvs_num./wvs_den;
            
            % Put y axis values into an array
            z = [0:1:1000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, u_mm, pn, fn, phi_r, w_rs, phi_z, w_vs)];
            h = [compute_ur(app, u_mm, pn, fn)];
            v = [compute_w(app, u_mm, phi_r, w_rs, phi_z, w_vs)];

            % Arrange arrays into a table
            ar = [m; h; v; z];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2", "Var3", "Var4"], ["Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]","Height above ground [m]"]);

            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Close request function: UIFigure
        function LiCloseRequest(app, event)
            % Reenable Li button
            app.CallingApp.Lietal2012Button.Enable = 'on';

            % Close Li window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @LiCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.VerticalAxes = uiaxes(app.UIFigure);
            title(app.VerticalAxes, 'Vertical Profile of Velocity')
            xlabel(app.VerticalAxes, 'Velocity [m/s]')
            ylabel(app.VerticalAxes, 'Height above ground [m]')
            zlabel(app.VerticalAxes, 'Z')
            app.Lietal2012Label = uilabel(app.UIFigure);
            app.Lietal2012Label.Text = 'Li et al. (2012)';
            app.RadialProfileModelParametersPanel = uipanel(app.UIFigure);
            app.RadialProfileModelParametersPanel.Title = 'Radial Profile: Model Parameters';
            app.MaximumhorizontalvelocitymsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxhorizontalvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxhorizontalvelSlider.Value = 40;
            app.MaxhorizontalvelField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField.Limits = [0 100];
            app.MaxhorizontalvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxhorizontalvelField.Value = 40;
            app.RadiusofmaximumhorizontalvelocitykmLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.RadiusofmaximumhorizontalvelocitykmLabel.Text = 'Radius of maximum horizontal velocity [km]';
            app.maxrSlider = uislider(app.RadialProfileModelParametersPanel);
            app.maxrSlider.Limits = [0 5];
            app.maxrSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.maxrSlider.Value = 1;
            app.maxrField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.maxrField.Limits = [0 5];
            app.maxrField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.maxrField.Value = 1;
            app.HeightofmaximumhorizontalvelocitymLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.HeightofmaximumhorizontalvelocitymLabel.Text = 'Height of maximum horizontal velocity [m]';
            app.maxzSlider = uislider(app.RadialProfileModelParametersPanel);
            app.maxzSlider.Limits = [0 1000];
            app.maxzSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.maxzSlider.Value = 100;
            app.maxzField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.maxzField.Limits = [0 1000];
            app.maxzField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.maxzField.Value = 100;
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            app.VerticalProfileModelParametersPanel = uipanel(app.UIFigure);
            app.VerticalProfileModelParametersPanel.Title = 'Vertical Profile: Model Parameters';
            app.MaximumhorizontalvelocitymsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel_2.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxhorizontalvelSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxhorizontalvelSlider_2.Value = 40;
            app.MaxhorizontalvelField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField_2.Limits = [0 100];
            app.MaxhorizontalvelField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxhorizontalvelField_2.Value = 40;
            app.RadiusofmaximumhorizontalvelocitykmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.RadiusofmaximumhorizontalvelocitykmLabel_2.Text = 'Radius of maximum horizontal velocity [km]';
            app.maxrSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.maxrSlider_2.Limits = [0 5];
            app.maxrSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.maxrSlider_2.Value = 1;
            app.maxrField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.maxrField_2.Limits = [0 5];
            app.maxrField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.maxrField_2.Value = 1;
            app.HeightofmaximumhorizontalvelocitymLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.HeightofmaximumhorizontalvelocitymLabel_2.Text = 'Height of maximum horizontal velocity [m]';
            app.maxzSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.maxzSlider_2.Limits = [0 1000];
            app.maxzSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.maxzSlider_2.Value = 100;
            app.maxzField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.maxzField_2.Limits = [0 1000];
            app.maxzField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.maxzField_2.Value = 100;
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_2Pushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton_2Pushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.zmLabel_2 = uilabel(app.Panel_2);
            app.zmLabel_2.Text = 'Distance from downdraft center [km]';
            app.rField = uieditfield(app.Panel_2, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.rField.Value = 1;
            app.rSlider = uislider(app.Panel_2);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.rSlider.Value = 1;
            app.Panel_3 = uipanel(app.UIFigure);
            app.FixedParametersCheckBox = uicheckbox(app.Panel_3);
            app.FixedParametersCheckBox.Text = 'Keep model parameters fixed';
            app.Panel = uipanel(app.UIFigure);
            app.zmLabel = uilabel(app.Panel);
            app.zmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.Panel);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.Panel, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zField.Value = 100;
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.jweia.2011.12.004';
            app.Hyperlink.Text = 'Corresponding Paper';
            app.PlottingOptionsPanel_2 = uipanel(app.UIFigure);
            app.PlottingOptionsPanel_2.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel_2);
            app.PlotmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlothorizontalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel_2);
            app.PlothorizontalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlothorizontalvelocityCheckBox.Text = 'Plot horizontal velocity';
            app.PlotverticalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel_2);
            app.PlotverticalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotverticalvelocityCheckBox.Text = 'Plot vertical velocity';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Li(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end