classdef MoeiniRomanic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Hyperlink                      matlab.ui.control.Hyperlink
        ExportPlotButton_4             matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        zField                         matlab.ui.control.NumericEditField
        zSlider                        matlab.ui.control.Slider
        HeightabovegroundmLabel        matlab.ui.control.Label
        ExportDataTableButton_3        matlab.ui.control.Button
        RadialProfileModelParametersPanel  matlab.ui.container.Panel
        Image_3                        matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        cwField_2                      matlab.ui.control.NumericEditField
        cwSlider_2                     matlab.ui.control.Slider
        CoefficientofcontractionLabel  matlab.ui.control.Label
        InitialvelField_2              matlab.ui.control.NumericEditField
        InitialvelSlider_2             matlab.ui.control.Slider
        DowndraftvelocitymsLabel       matlab.ui.control.Label
        DiameterField_2                matlab.ui.control.NumericEditField
        DiameterSlider_2               matlab.ui.control.Slider
        DowndraftdiameterkmLabel       matlab.ui.control.Label
        ABLHvelField_2                 matlab.ui.control.NumericEditField
        ABLHvelSlider_2                matlab.ui.control.Slider
        ABLwindspeedmsLabel            matlab.ui.control.Label
        HField_2                       matlab.ui.control.NumericEditField
        HSlider_2                      matlab.ui.control.Slider
        CloudbaseheightkmLabel_2       matlab.ui.control.Label
        PlottingOptionsPanel           matlab.ui.container.Panel
        FixedParametersCheckBox        matlab.ui.control.CheckBox
        ExportDataTableButton_2        matlab.ui.control.Button
        ExportPlotButton_2             matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        rField                         matlab.ui.control.NumericEditField
        rSlider                        matlab.ui.control.Slider
        DistancefromdowndraftcenterkmLabel  matlab.ui.control.Label
        VerticalProfileModelParametersPanel  matlab.ui.container.Panel
        Image_4                        matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        cwField                        matlab.ui.control.NumericEditField
        cwSlider                       matlab.ui.control.Slider
        CoefficientofcontractionLabel_2  matlab.ui.control.Label
        InitialvelField                matlab.ui.control.NumericEditField
        InitialvelSlider               matlab.ui.control.Slider
        DowndraftvelocitymsLabel_2     matlab.ui.control.Label
        DiameterField                  matlab.ui.control.NumericEditField
        DiameterSlider                 matlab.ui.control.Slider
        DowndraftdiameterkmLabel_2     matlab.ui.control.Label
        HField                         matlab.ui.control.NumericEditField
        ABLHvelField                   matlab.ui.control.NumericEditField
        ABLHvelSlider                  matlab.ui.control.Slider
        ABLwindspeedmsLabel_2          matlab.ui.control.Label
        HSlider                        matlab.ui.control.Slider
        CloudbaseheightkmLabel         matlab.ui.control.Label
        MoeiniandRomanic2023Label      matlab.ui.control.Label
        HorizontalAxes                 matlab.ui.control.UIAxes
        VerticalAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)
        
        function uz = compute_uz(app, u_db, u_abl, u_nl)
            % Total velocity for vertical profile (Equation 41)
            uz = u_db + u_abl' - u_nl;
        end

        function ur = compute_ur(app, u_db, u_abl, u_nl)
            % Total velocity for radial profile (Equation 41)
            ur = u_db + u_abl' - u_nl;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller7)
            % Store selection menu app object
            app.CallingApp = caller7;
            
            % Set up plotting options menu
            app.FixedParametersCheckBox.Value = 1;

            % Define parameters for vertical profile
            z = 0:1:1000;
            u_H = app.ABLHvelField.Value; % ABL wind speed
            u_j = app.InitialvelField.Value; % Downdraft velocity
            D = app.DiameterField.Value * 1000;
            H = app.HField.Value * 1000;
            r = app.rField.Value * 1000;
            c_w = app.cwField_2.Value;
            lambda_j = u_j/u_H; % Strength of jet relative to ambient flow (Equation 3)
            z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9)); % Equation 43
            z_mb = 0.0196*H*(r/H)^0.9; % Equation 41
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*((z./(H*(r/H)^0.9)).^0.305))/((r/H)^1.1); % Equation 42
            u_nl = (6.251.*u_db.*(r/H)^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H)); % Nonlinear interaction (Equation 41)
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)
            u_abl = zeros(length(z), 1); % Equation 11
            for i = 1:length(z)
                if z(i) <= z_m
                    u_abl(i) = u_H * (z(i)/z_m).^(1/7);
                else
                    u_abl(i) = u_H;
                end
            end

            % Plot vertical profile
            plot(app.VerticalAxes, compute_uz(app, u_db, u_abl, u_nl), z, 'k');
            p = app.VerticalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust vertical axis
            xlim(app.VerticalAxes, [0, inf])

            % Define parameters for radial profile
            r = 0:1:10000;
            u_H = app.ABLHvelField_2.Value;
            u_j = app.InitialvelField_2.Value;
            D = app.DiameterField_2.Value * 1000;
            H = app.HField_2.Value * 1000;
            z = app.zField.Value;
            c_w = app.cwField.Value;
            lambda_j = u_j/u_H;
            z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
            z_mb = 0.0196.*H.*(r./H).^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H.*(r./H).^0.9)).*((z./(H.*(r./H).^0.9)).^0.305))./((r./H).^1.1);
            u_nl = (6.251.*u_db.*(r./H).^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)
            u_abl = zeros(length(r), 1);
            for i = 1:length(r)
                if z <= z_m(i)
                    u_abl(i) = u_H * (z/z_m(i)).^(1/7);
                else
                    u_abl(i) = u_H;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_ur(app, u_db, u_abl, u_nl), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;
        end

        % Value changed function: ABLHvelField, DiameterField, HField, 
        % ...and 3 other components
        function FieldValueChanged(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            u_H = app.ABLHvelField.Value;
            u_j = app.InitialvelField.Value;
            D = app.DiameterField.Value * 1000;
            H = app.HField.Value * 1000;
            r = app.rField.Value * 1000;
            c_w = app.cwField.Value;
            lambda_j = u_j/u_H;
            z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
            z_mb = 0.0196*H*(r/H)^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*((z./(H*(r/H)^0.9)).^0.305))/((r/H)^1.1);
            u_nl = (6.251.*u_db.*(r/H)^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)
            u_abl = zeros(length(z), 1);
            for i = 1:length(z)
                if z(i) <= z_m
                    u_abl(i) = u_H * (z(i)/z_m).^(1/7);
                else
                    u_abl(i) = u_H;
                end
            end

            % Plot vertical profile
            plot(app.VerticalAxes, compute_uz(app, u_db, u_abl, u_nl), z, 'k');
            p = app.VerticalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis
            xlim(app.VerticalAxes, [0, inf])

            % Update sliders to match field values
            if app.ABLHvelField.Value ~= app.ABLHvelSlider.Value
                app.ABLHvelSlider.Value = app.ABLHvelField.Value;
            end
            if app.InitialvelField.Value ~= app.InitialvelSlider.Value
                app.InitialvelSlider.Value = app.InitialvelField.Value;
            end
            if app.DiameterField.Value ~= app.DiameterSlider.Value
                app.DiameterSlider.Value = app.DiameterField.Value;
            end
            if app.HField.Value ~= app.HSlider.Value
                app.HSlider.Value = app.HField.Value;
            end
            if app.cwField.Value ~= app.cwSlider.Value
                app.cwSlider.Value = app.cwField.Value;
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.ABLHvelField_2.Value = app.ABLHvelField.Value;
                app.ABLHvelSlider_2.Value = app.ABLHvelSlider.Value;
                app.InitialvelField_2.Value = app.InitialvelField.Value;
                app.InitialvelSlider_2.Value = app.InitialvelSlider.Value;
                app.DiameterField_2.Value = app.DiameterField.Value;
                app.DiameterSlider_2.Value = app.DiameterSlider.Value;
                app.HField_2.Value = app.HField.Value;
                app.HSlider_2.Value = app.HSlider.Value;
                app.cwField_2.Value = app.cwField.Value;
                app.cwSlider_2.Value = app.cwSlider.Value;

                % Define parameters for radial profile
                r = 0:1:10000;
                u_H = app.ABLHvelField_2.Value;
                u_j = app.InitialvelField_2.Value;
                D = app.DiameterField_2.Value * 1000;
                H = app.HField_2.Value * 1000;
                z = app.zField.Value;
                c_w = app.cwField.Value;
                lambda_j = u_j/u_H;
                z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
                z_mb = 0.0196.*H.*(r./H).^0.9;
                u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H.*(r./H).^0.9)).*((z./(H.*(r./H).^0.9)).^0.305))./((r./H).^1.1);
                u_nl = (6.251.*u_db.*(r./H).^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
                % (gamma_0 - epsilon_0) = 2.6 (equation 40)
                u_abl = zeros(length(r), 1);
                for i = 1:length(r)
                    if z <= z_m(i)
                        u_abl(i) = u_H * (z/z_m(i)).^(1/7);
                    else
                        u_abl(i) = u_H;
                    end
                end
    
                % Plot radial profile
                plot(app.HorizontalAxes, r, compute_ur(app, u_db, u_abl, u_nl), 'k');
                p = app.HorizontalAxes.Children;
                p.LineWidth = 1.5;
            end
        end

        % Value changed function: ABLHvelSlider, DiameterSlider, HSlider, 
        % ...and 3 other components
        function SliderValueChanged(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            u_H = app.ABLHvelSlider.Value;
            u_j = app.InitialvelSlider.Value;
            D = app.DiameterSlider.Value * 1000;
            H = app.HSlider.Value * 1000;
            r = app.rSlider.Value * 1000;
            c_w = app.cwSlider.Value;
            lambda_j = u_j/u_H;
            z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
            z_mb = 0.0196*H*(r/H)^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*((z./(H*(r/H)^0.9)).^0.305))/((r/H)^1.1);
            u_nl = (6.251.*u_db.*(r/H)^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)
            u_abl = zeros(length(z), 1);
            for i = 1:length(z)
                if z(i) <= z_m
                    u_abl(i) = u_H * (z(i)/z_m).^(1/7);
                else
                    u_abl(i) = u_H;
                end
            end

            % Plot vertical profile
            plot(app.VerticalAxes, compute_uz(app, u_db, u_abl, u_nl), z, 'k');
            p = app.VerticalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis
            xlim(app.VerticalAxes, [0, inf])
            
            % Update field values to match sliders
            if app.ABLHvelSlider.Value ~= app.ABLHvelField.Value
                app.ABLHvelField.Value = app.ABLHvelSlider.Value;
            end
            if app.InitialvelSlider.Value ~= app.InitialvelField.Value
                app.InitialvelField.Value = app.InitialvelSlider.Value;
            end
            if app.DiameterSlider.Value ~= app.DiameterField.Value
                app.DiameterField.Value = app.DiameterSlider.Value;
            end
            if app.HSlider.Value ~= app.HField.Value
                app.HField.Value = app.HSlider.Value;
            end
            if app.cwSlider.Value ~= app.cwField.Value
                app.cwField.Value = app.cwSlider.Value;
            end
            if app.rSlider.Value ~= app.rField.Value
                app.rField.Value = app.rSlider.Value;
            end

            % Update field values if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.ABLHvelField_2.Value = app.ABLHvelField.Value;
                app.ABLHvelSlider_2.Value = app.ABLHvelSlider.Value;
                app.InitialvelField_2.Value = app.InitialvelField.Value;
                app.InitialvelSlider_2.Value = app.InitialvelSlider.Value;
                app.DiameterField_2.Value = app.DiameterField.Value;
                app.DiameterSlider_2.Value = app.DiameterSlider.Value;
                app.HField_2.Value = app.HField.Value;
                app.HSlider_2.Value = app.HSlider.Value;
                app.cwField_2.Value = app.cwField.Value;
                app.cwSlider_2.Value = app.cwSlider.Value;

                % Define parameters for radial profile
                r = 0:1:10000;
                u_H = app.ABLHvelField_2.Value;
                u_j = app.InitialvelField_2.Value;
                D = app.DiameterField_2.Value * 1000;
                H = app.HField_2.Value * 1000;
                z = app.zField.Value;
                c_w = app.cwField_2.Value;
                lambda_j = u_j/u_H;
                z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
                z_mb = 0.0196.*H.*(r./H).^0.9;
                u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H.*(r./H).^0.9)).*((z./(H.*(r./H).^0.9)).^0.305))./((r./H).^1.1);
                u_nl = (6.251.*u_db.*(r./H).^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
                % (gamma_0 - epsilon_0) = 2.6 (equation 40)
                u_abl = zeros(length(r), 1);
                for i = 1:length(r)
                    if z <= z_m(i)
                        u_abl(i) = u_H * (z/z_m(i)).^(1/7);
                    else
                        u_abl(i) = u_H;
                    end
                end
    
                % Plot radial profile
                plot(app.HorizontalAxes, r, compute_ur(app, u_db, u_abl, u_nl), 'k');
                p = app.HorizontalAxes.Children;
                p.LineWidth = 1.5;
            end
        end

        % Value changed function: ABLHvelField_2, DiameterField_2, 
        % ...and 4 other components
        function FieldValue2Changed(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_H = app.ABLHvelField_2.Value;
            u_j = app.InitialvelField_2.Value;
            D = app.DiameterField_2.Value * 1000;
            H = app.HField_2.Value * 1000;
            z = app.zField.Value;
            c_w = app.cwField_2.Value;
            lambda_j = u_j/u_H;
            z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
            z_mb = 0.0196.*H.*(r./H).^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H.*(r./H).^0.9)).*((z./(H.*(r./H).^0.9)).^0.305))./((r./H).^1.1);
            u_nl = (6.251.*u_db.*(r./H).^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)
            u_abl = zeros(length(r), 1);
            for i = 1:length(r)
                if z <= z_m(i)
                    u_abl(i) = u_H * (z/z_m(i)).^(1/7);
                else
                    u_abl(i) = u_H;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_ur(app, u_db, u_abl, u_nl), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Update sliders to match field values
            if app.ABLHvelField_2.Value ~= app.ABLHvelSlider_2.Value
                app.ABLHvelSlider_2.Value = app.ABLHvelField_2.Value;
            end
            if app.InitialvelField_2.Value ~= app.InitialvelSlider_2.Value
                app.InitialvelSlider_2.Value = app.InitialvelField_2.Value;
            end
            if app.DiameterField_2.Value ~= app.DiameterSlider_2.Value
                app.DiameterSlider_2.Value = app.DiameterField_2.Value;
            end
            if app.HField_2.Value ~= app.HSlider_2.Value
                app.HSlider_2.Value = app.HField_2.Value;
            end
            if app.cwField_2.Value ~= app.cwSlider_2.Value
                app.cwSlider_2.Value = app.cwField_2.Value;
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.ABLHvelField.Value = app.ABLHvelField_2.Value;
                app.ABLHvelSlider.Value = app.ABLHvelSlider_2.Value;
                app.InitialvelField.Value = app.InitialvelField_2.Value;
                app.InitialvelSlider.Value = app.InitialvelSlider_2.Value;
                app.DiameterField.Value = app.DiameterField_2.Value;
                app.DiameterSlider.Value = app.DiameterSlider_2.Value;
                app.HField.Value = app.HField_2.Value;
                app.HSlider.Value = app.HSlider_2.Value;
                app.cwField.Value = app.cwField_2.Value;
                app.cwSlider.Value = app.cwSlider_2.Value;

                % Define parameters for vertical profile
                z = 0:1:1000;
                u_H = app.ABLHvelField.Value;
                u_j = app.InitialvelField.Value;
                D = app.DiameterField.Value * 1000;
                H = app.HField.Value * 1000;
                r = app.rField.Value * 1000;
                c_w = app.cwField.Value;
                lambda_j = u_j/u_H;
                z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
                z_mb = 0.0196*H*(r/H)^0.9;
                u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*((z./(H*(r/H)^0.9)).^0.305))/((r/H)^1.1);
                u_nl = (6.251.*u_db.*(r/H)^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
                % (gamma_0 - epsilon_0) = 2.6 (equation 40)
                u_abl = zeros(length(z), 1);
                for i = 1:length(z)
                    if z(i) <= z_m
                        u_abl(i) = u_H * (z(i)/z_m).^(1/7);
                    else
                        u_abl(i) = u_H;
                    end
                end
    
                % Plot vertical profile
                plot(app.VerticalAxes, compute_uz(app, u_db, u_abl, u_nl), z, 'k');
                p = app.VerticalAxes.Children;
                p.LineWidth = 1.5;
            end
        end

        % Value changed function: ABLHvelSlider_2, DiameterSlider_2, 
        % ...and 4 other components
        function SliderValue2Changed(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_H = app.ABLHvelSlider_2.Value;
            u_j = app.InitialvelSlider_2.Value;
            D = app.DiameterSlider_2.Value * 1000;
            H = app.HSlider_2.Value * 1000;
            z = app.zSlider.Value;
            c_w = app.cwSlider_2.Value;
            lambda_j = u_j/u_H;
            z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
            z_mb = 0.0196.*H.*(r./H).^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H.*(r./H).^0.9)).*((z./(H.*(r./H).^0.9)).^0.305))./((r./H).^1.1);
            u_nl = (6.251.*u_db.*(r./H).^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)
            u_abl = zeros(length(r), 1);
            for i = 1:length(r)
                if z <= z_m(i)
                    u_abl(i) = u_H * (z/z_m(i)).^(1/7);
                else
                    u_abl(i) = u_H;
                end
            end

            % Plot vertical profile
            plot(app.HorizontalAxes, r, compute_ur(app, u_db, u_abl, u_nl), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;
            
            % Update field values to match sliders
            if app.ABLHvelSlider_2.Value ~= app.ABLHvelField_2.Value
                app.ABLHvelField_2.Value = app.ABLHvelSlider_2.Value;
            end
            if app.InitialvelSlider_2.Value ~= app.InitialvelField_2.Value
                app.InitialvelField_2.Value = app.InitialvelSlider_2.Value;
            end
            if app.DiameterSlider_2.Value ~= app.DiameterField_2.Value
                app.DiameterField_2.Value = app.DiameterSlider_2.Value;
            end
            if app.HSlider_2.Value ~= app.HField_2.Value
                app.HField_2.Value = app.HSlider_2.Value;
            end
            if app.cwSlider_2.Value ~= app.cwField_2.Value
                app.cwField_2.Value = app.cwSlider_2.Value;
            end
            if app.zSlider.Value ~= app.zField.Value
                app.zField.Value = app.zSlider.Value;
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.ABLHvelField.Value = app.ABLHvelField_2.Value;
                app.ABLHvelSlider.Value = app.ABLHvelSlider_2.Value;
                app.InitialvelField.Value = app.InitialvelField_2.Value;
                app.InitialvelSlider.Value = app.InitialvelSlider_2.Value;
                app.DiameterField.Value = app.DiameterField_2.Value;
                app.DiameterSlider.Value = app.DiameterSlider_2.Value;
                app.HField.Value = app.HField_2.Value;
                app.HSlider.Value = app.HSlider_2.Value;
                app.cwField.Value = app.cwField_2.Value;
                app.cwSlider.Value = app.cwSlider_2.Value;

                % Define parameters for vertical profile
                z = 0:1:1000;
                u_H = app.ABLHvelField.Value;
                u_j = app.InitialvelField.Value;
                D = app.DiameterField.Value * 1000;
                H = app.HField.Value * 1000;
                r = app.rField.Value * 1000;
                c_w = app.cwField.Value;
                lambda_j = u_j/u_H;
                z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
                z_mb = 0.0196*H*(r/H)^0.9;
                u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*((z./(H*(r/H)^0.9)).^0.305))/((r/H)^1.1);
                u_nl = (6.251.*u_db.*(r/H)^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
                % (gamma_0 - epsilon_0) = 2.6 (equation 40)
                u_abl = zeros(length(z), 1);
                for i = 1:length(z)
                    if z(i) <= z_m
                        u_abl(i) = u_H * (z(i)/z_m).^(1/7);
                    else
                        u_abl(i) = u_H;
                    end
                end
    
                % Plot vertical profile
                plot(app.VerticalAxes, compute_uz(app, u_db, u_abl, u_nl), z, 'k');
                p = app.VerticalAxes.Children;
                p.LineWidth = 1.5;
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the vertical profile plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.VerticalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.VerticalAxes.XLim;
            figAxes.YLim = app.VerticalAxes.YLim;
            figAxes.ZLim = app.VerticalAxes.ZLim;
            figAxes.DataAspectRatio = app.VerticalAxes.DataAspectRatio;
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Vertical Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            u_H = app.ABLHvelField.Value;
            u_j = app.InitialvelField.Value;
            D = app.DiameterField.Value * 1000;
            H = app.HField.Value * 1000;
            r = app.rField.Value * 1000;
            c_w = app.cwField.Value;
            lambda_j = u_j/u_H;
            z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
            z_mb = 0.0196*H*(r/H)^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*((z./(H*(r/H)^0.9)).^0.305))/((r/H)^1.1);
            u_nl = (6.251.*u_db.*(r/H)^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)
            u_abl = zeros(length(z), 1);
            for i = 1:length(z)
                if z(i) <= z_m
                    u_abl(i) = u_H * (z(i)/z_m).^(1/7);
                else
                    u_abl(i) = u_H;
                end
            end
            
            % Put y axis values into an array
            z = [0:1:1000];
            % Compute velocities and insert into array
            m = [compute_uz(app, u_db, u_abl, u_nl)];

            % Arrange arrays into a table
            ar = [m; z];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2"], ["Velocity [m/s]","Height above ground [m]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Button pushed function: ExportPlotButton_4
        function ExportPlotButton2Pushed(app, event)
            % Create a temporary figure of the radial profile plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end
            
            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_3
        function ExportDataTableButton2Pushed(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_H = app.ABLHvelField_2.Value;
            u_j = app.InitialvelField_2.Value;
            D = app.DiameterField_2.Value * 1000;
            H = app.HField_2.Value * 1000;
            z = app.zField.Value;
            c_w = app.cwField_2.Value;
            lambda_j = u_j/u_H;
            z_m = H .* (((0.0769.*u_H.*(r./H).^1.1)/(sqrt(c_w)*(D/H)*u_j)) + 0.203) .* (((0.03983*(2.6-0.112923).*(r./H).^2)/(sqrt(c_w)*(D/H)*lambda_j)) + (0.098.*(r./H).^0.9));
            z_mb = 0.0196.*H.*(r./H).^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H.*(r./H).^0.9)).*((z./(H.*(r./H).^0.9)).^0.305))./((r./H).^1.1);
            u_nl = (6.251.*u_db.*(r./H).^0.2.*(-0.1129 + 2.6).*((z-z_mb)/H))./(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)
            u_abl = zeros(length(r), 1);
            for i = 1:length(r)
                if z <= z_m(i)
                    u_abl(i) = u_H * (z/z_m(i)).^(1/7);
                else
                    u_abl(i) = u_H;
                end
            end
            
            % Put y axis values into an array
            r = [0:1:10000];
            % Compute velocities and insert into array
            m = [compute_ur(app, u_db, u_abl, u_nl)];

            % Arrange arrays into a table
            ar = [r; m];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2"], ["Distance from downdraft center [m]","Velocity [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= ""
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Close request function: UIFigure
        function MoeiniRomanicCloseRequest(app, event)
            % Reenable Moeini Romanic button
            app.CallingApp.MoeiniandRomanic2023Button.Enable = 'on';

            % Close Moeini Romanic window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MoeiniRomanicCloseRequest, true);
            app.VerticalAxes = uiaxes(app.UIFigure);
            title(app.VerticalAxes, 'Vertical Profile of Velocity')
            xlabel(app.VerticalAxes, 'Velocity [m/s]')
            ylabel(app.VerticalAxes, 'Height above ground [m]')
            zlabel(app.VerticalAxes, 'Z')
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.MoeiniandRomanic2023Label = uilabel(app.UIFigure);
            app.MoeiniandRomanic2023Label.Text = 'Moeini and Romanic (2023)';
            app.VerticalProfileModelParametersPanel = uipanel(app.UIFigure);
            app.VerticalProfileModelParametersPanel.Title = 'Vertical Profile: Model Parameters';
            app.CloudbaseheightkmLabel = uilabel(app.VerticalProfileModelParametersPanel);
            app.CloudbaseheightkmLabel.Text = 'Cloud-base height [km]';
            app.HSlider = uislider(app.VerticalProfileModelParametersPanel);
            app.HSlider.Limits = [0 5];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.HSlider.Value = 3;
            app.ABLwindspeedmsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.ABLwindspeedmsLabel_2.Tooltip = {''};
            app.ABLwindspeedmsLabel_2.Text = 'ABLwind speed [m/s]';
            app.ABLHvelSlider = uislider(app.VerticalProfileModelParametersPanel);
            app.ABLHvelSlider.Limits = [0 30];
            app.ABLHvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ABLHvelSlider.Tooltip = {''};
            app.ABLHvelSlider.Value = 5;
            app.ABLHvelField = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.ABLHvelField.Limits = [0 30];
            app.ABLHvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ABLHvelField.Tooltip = {''};
            app.ABLHvelField.Value = 5;
            app.HField = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.HField.Limits = [0 5];
            app.HField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.HField.Value = 3;
            app.DowndraftdiameterkmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.DowndraftdiameterkmLabel_2.Text = 'Downdraft diameter [km]';
            app.DiameterSlider = uislider(app.VerticalProfileModelParametersPanel);
            app.DiameterSlider.Limits = [0 5];
            app.DiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.DiameterSlider.Value = 2;
            app.DiameterField = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.DiameterField.Limits = [0 5];
            app.DiameterField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.DiameterField.Value = 2;
            app.DowndraftvelocitymsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.DowndraftvelocitymsLabel_2.Text = 'Downdraft velocity [m/s]';
            app.InitialvelSlider = uislider(app.VerticalProfileModelParametersPanel);
            app.InitialvelSlider.Limits = [0 60];
            app.InitialvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.InitialvelSlider.Value = 25;
            app.InitialvelField = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.InitialvelField.Limits = [0 60];
            app.InitialvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.InitialvelField.Value = 25;
            app.CoefficientofcontractionLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.CoefficientofcontractionLabel_2.Text = 'Coefficient of contraction';
            app.cwSlider = uislider(app.VerticalProfileModelParametersPanel);
            app.cwSlider.Limits = [0 1];
            app.cwSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.cwSlider.Value = 1;
            app.cwField = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.cwField.Limits = [0 1];
            app.cwField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.cwField.Value = 1;
            app.Image_2 = uiimage(app.VerticalProfileModelParametersPanel);
            app.Image_2.Tooltip = {'ABL winds are much weaker than the downdraft velocity.'};
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.Image_4 = uiimage(app.VerticalProfileModelParametersPanel);
            app.Image_4.Tooltip = {'Default value is 1.'};
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.Panel = uipanel(app.UIFigure);
            app.DistancefromdowndraftcenterkmLabel = uilabel(app.Panel);
            app.DistancefromdowndraftcenterkmLabel.Tooltip = {'The distance from downdraft center must be greater than the downdraft diameter and less than 13.2 * cloud-base height.'};
            app.DistancefromdowndraftcenterkmLabel.Text = 'Distance from downdraft center [km]';
            app.rSlider = uislider(app.Panel);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rSlider.Tooltip = {'The distance from downdraft center must be greater than the downdraft diameter and less than 13.2 * cloud-base height.'};
            app.rSlider.Value = 3;
            app.rField = uieditfield(app.Panel, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rField.Tooltip = {'The distance from the downdraft center must be greater than the diameter of the downdraft and less than 13.2 * cloud-base height.'};
            app.rField.Value = 3;
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.FixedParametersCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.FixedParametersCheckBox.Text = 'Keep model parameters fixed';
            app.RadialProfileModelParametersPanel = uipanel(app.UIFigure);
            app.RadialProfileModelParametersPanel.Title = 'Radial Profile: Model Parameters';
            app.CloudbaseheightkmLabel_2 = uilabel(app.RadialProfileModelParametersPanel);
            app.CloudbaseheightkmLabel_2.Text = 'Cloud-base height [km]';
            app.HSlider_2 = uislider(app.RadialProfileModelParametersPanel);
            app.HSlider_2.Limits = [0 5];
            app.HSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.HSlider_2.Value = 3;
            app.HField_2 = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.HField_2.Limits = [0 5];
            app.HField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.HField_2.Value = 3;
            app.ABLwindspeedmsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.ABLwindspeedmsLabel.Tooltip = {''};
            app.ABLwindspeedmsLabel.Text = 'ABL wind speed [m/s]';
            app.ABLHvelSlider_2 = uislider(app.RadialProfileModelParametersPanel);
            app.ABLHvelSlider_2.Limits = [0 30];
            app.ABLHvelSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.ABLHvelSlider_2.Tooltip = {''};
            app.ABLHvelSlider_2.Value = 5;
            app.ABLHvelField_2 = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.ABLHvelField_2.Limits = [0 30];
            app.ABLHvelField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.ABLHvelField_2.Tooltip = {''};
            app.ABLHvelField_2.Value = 5;
            app.DowndraftdiameterkmLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.DowndraftdiameterkmLabel.Text = 'Downdraft diameter [km]';
            app.DiameterSlider_2 = uislider(app.RadialProfileModelParametersPanel);
            app.DiameterSlider_2.Limits = [0 5];
            app.DiameterSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.DiameterSlider_2.Value = 2;
            app.DiameterField_2 = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.DiameterField_2.Limits = [0 5];
            app.DiameterField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.DiameterField_2.Value = 2;
            app.DowndraftvelocitymsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.DowndraftvelocitymsLabel.Text = 'Downdraft velocity [m/s]';
            app.InitialvelSlider_2 = uislider(app.RadialProfileModelParametersPanel);
            app.InitialvelSlider_2.Limits = [0 60];
            app.InitialvelSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.InitialvelSlider_2.Value = 25;
            app.InitialvelField_2 = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.InitialvelField_2.Limits = [0 60];
            app.InitialvelField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.InitialvelField_2.Value = 25;
            app.CoefficientofcontractionLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.CoefficientofcontractionLabel.Text = 'Coefficient of contraction';
            app.cwSlider_2 = uislider(app.RadialProfileModelParametersPanel);
            app.cwSlider_2.Limits = [0 1];
            app.cwSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.cwSlider_2.Value = 1;
            app.cwField_2 = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.cwField_2.Limits = [0 1];
            app.cwField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.cwField_2.Value = 1;
            app.Image = uiimage(app.RadialProfileModelParametersPanel);
            app.Image.Tooltip = {'ABL winds are much weaker than the downdraft velocity.'};
            app.Image.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.Image_3 = uiimage(app.RadialProfileModelParametersPanel);
            app.Image_3.Tooltip = {'Default value is 1.'};
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.ExportDataTableButton_3 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton2Pushed, true);
            app.ExportDataTableButton_3.Text = {'Export Data Table'; ''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.HeightabovegroundmLabel = uilabel(app.Panel_2);
            app.HeightabovegroundmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.Panel_2);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.Panel_2, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.zField.Value = 100;
            app.ExportPlotButton_4 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton2Pushed, true);
            app.ExportPlotButton_4.Text = 'Export Plot';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1175/JAS-D-22-0123.1';
            app.Hyperlink.Text = 'Corresponding Paper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MoeiniRomanic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end