classdef OsegueraBowles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        Panel_3                         matlab.ui.container.Panel
        FixedParametersCheckBox         matlab.ui.control.CheckBox
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlotverticalvelocityCheckBox    matlab.ui.control.CheckBox
        PlothorizontalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        rSlider                         matlab.ui.control.Slider
        zmLabel_2                       matlab.ui.control.Label
        rField                          matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        zField                          matlab.ui.control.NumericEditField
        zSlider                         matlab.ui.control.Slider
        zmLabel                         matlab.ui.control.Label
        RadialProfileModelParametersPanel  matlab.ui.container.Panel
        MaxzField                       matlab.ui.control.NumericEditField
        MaxhorizontalvelField           matlab.ui.control.NumericEditField
        DowndraftdiameterField          matlab.ui.control.NumericEditField
        DowndraftdiameterSlider         matlab.ui.control.Slider
        DowndraftdiameterkmLabel        matlab.ui.control.Label
        MaxhorizontalvelSlider          matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel  matlab.ui.control.Label
        MaxzSlider                      matlab.ui.control.Slider
        HeightofmaximumhorizontalvelocitymLabel  matlab.ui.control.Label
        ExportDataTableButton_2         matlab.ui.control.Button
        ExportPlotButton_2              matlab.ui.control.Button
        VerticalProfileModelParametersPanel  matlab.ui.container.Panel
        MaxzField_2                     matlab.ui.control.NumericEditField
        MaxhorizontalvelField_2         matlab.ui.control.NumericEditField
        DowndraftdiameterField_2        matlab.ui.control.NumericEditField
        DowndraftdiameterSlider_2       matlab.ui.control.Slider
        DowndraftradiuskmLabel_2        matlab.ui.control.Label
        MaxhorizontalvelSlider_2        matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel_2  matlab.ui.control.Label
        MaxzSlider_2                    matlab.ui.control.Slider
        HeightofmaximumhorizontalvelocitymLabel_2  matlab.ui.control.Label
        ExportDataTableButton           matlab.ui.control.Button
        ExportPlotButton                matlab.ui.control.Button
        OsegueraandBowles1988Label      matlab.ui.control.Label
        VerticalAxes                    matlab.ui.control.UIAxes
        HorizontalAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)
        
        function u = compute_u(app, R, ch_o, ch_i, sf, r, z)
            % Horizontal downburst velocity (Equation 5)
            u_r = ((sf * R^2)./(2 .* r)) .* (1-exp(-(r./R).^2)) .* (exp(-z/ch_o) - exp(-z/ch_i));

            % Vertical downburst velocity (Equation 6)
            u_z = (-sf .* exp(-(r./R).^2)) .* (ch_i * (exp(-z/ch_i) - 1) - (ch_o * (exp(-z/ch_o) - 1)));

            % Downburst velocity magnitude 
            u = sqrt(u_r.^2 + u_z.^2);
        end
        
        function u_r = compute_ur(app, R, ch_o, ch_i, sf, r, z)
            % Horizontal downburst velocity (Equation 5)
            u_r = ((sf * R^2)./(2 .* r)) .* (1-exp(-(r./R).^2)) .* (exp(-z/ch_o) - exp(-z/ch_i));
        end
        
        function u_z = compute_uz(app, R, ch_o, ch_i, sf, r, z)
            % Vertical downburst velocity (Equation 6)
            u_z = (-sf .* exp(-(r./R).^2)) .* (ch_i * (exp(-z/ch_i) - 1) - (ch_o * (exp(-z/ch_o) - 1)));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            % Store selection menu app object
            app.CallingApp = caller;
            
            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlothorizontalvelocityCheckBox.Value = 1;
            app.PlotverticalvelocityCheckBox.Value = 1;
            app.FixedParametersCheckBox.Value = 1;
            
            % Define parameters for radial profile
            r = 0:1:10000;
            R = (app.DowndraftdiameterField.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelField.Value;
            z_max = app.MaxzField.Value;
            ch_o = z_max / 0.22; % Denoted as z^* in paper
            ch_i = ch_o / 12.5; % Denoted as epsilon in paper
            sf = u_max / (0.2357 * R); % Denoted as lambda in paper
            z = app.zField.Value;

            % Plot radial profile horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, R, ch_o, ch_i, sf, r, z), 'k', r, compute_ur(app, R, ch_o, ch_i, sf, r, z), 'r', r, compute_uz(app, R, ch_o, ch_i, sf, r, z), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Define parameters for vertical profile
            z = 0:1:1000;
            R = (app.DowndraftdiameterField_2.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelField_2.Value;
            z_max = app.MaxzField_2.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            r = app.rField.Value * 1000;

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, R, ch_o, ch_i, sf, r, z), z, 'k', compute_ur(app, R, ch_o, ch_i, sf, r, z), z, 'r', compute_uz(app, R, ch_o, ch_i, sf, r, z), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
        end

        % Value changed function: DowndraftdiameterField, 
        % ...and 3 other components
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            R = (app.DowndraftdiameterField.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelField.Value;
            z_max = app.MaxzField.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            z = app.zField.Value;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, R, ch_o, ch_i, sf, r, z), 'k', r, compute_ur(app, R, ch_o, ch_i, sf, r, z), 'r', r, compute_uz(app, R, ch_o, ch_i, sf, r, z), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update sliders to match field values
            if app.DowndraftdiameterField.Value ~= app.DowndraftdiameterSlider.Value
                app.DowndraftdiameterSlider.Value = app.DowndraftdiameterField.Value;
            else
            end
            if app.MaxhorizontalvelField.Value ~= app.MaxhorizontalvelSlider.Value
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelField.Value;
            else 
            end
            if app.MaxzField.Value ~= app.MaxzSlider.Value
                app.MaxzSlider.Value = app.MaxzField.Value;
            else 
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            else 
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.DowndraftdiameterField_2.Value = app.DowndraftdiameterField.Value;
                app.DowndraftdiameterSlider_2.Value = app.DowndraftdiameterSlider.Value;
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelField.Value;
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelSlider.Value;
                app.MaxzField_2.Value = app.MaxzField.Value;
                app.MaxzSlider_2.Value = app.MaxzSlider.Value;

                r = app.rField.Value * 1000;
                R = (app.DowndraftdiameterField_2.Value / 2) * 1000;
                u_max = app.MaxhorizontalvelField_2.Value;
                z_max = app.MaxzField_2.Value;
                ch_o = z_max / 0.22;
                ch_i = ch_o / 12.5; 
                sf = u_max / (0.2357 * R);
                z = 0:1:1000;

                plot(app.VerticalAxes, compute_u(app, R, ch_o, ch_i, sf, r, z), z, 'k', compute_ur(app, R, ch_o, ch_i, sf, r, z), z, 'r', compute_uz(app, R, ch_o, ch_i, sf, r, z), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: DowndraftdiameterSlider, 
        % ...and 3 other components
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            R = (app.DowndraftdiameterSlider.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelSlider.Value;
            z_max = app.MaxzSlider.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            z = app.zSlider.Value;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, R, ch_o, ch_i, sf, r, z), 'k', r, compute_ur(app, R, ch_o, ch_i, sf, r, z), 'r', r, compute_uz(app, R, ch_o, ch_i, sf, r, z), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.DowndraftdiameterSlider.Value ~= app.DowndraftdiameterField.Value
                app.DowndraftdiameterField.Value = app.DowndraftdiameterSlider.Value;            
            else
            end
            if app.MaxhorizontalvelSlider.Value ~= app.MaxhorizontalvelField.Value
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelSlider.Value;
            else
            end  
            if app.MaxzSlider.Value ~= app.MaxzField.Value
                app.MaxzField.Value = app.MaxzSlider.Value;
            else 
            end
            if app.zSlider.Value ~= app.zField.Value
                app.zField.Value = app.zSlider.Value;
            else
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.DowndraftdiameterSlider_2.Value = app.DowndraftdiameterSlider.Value;
                app.DowndraftdiameterField_2.Value = app.DowndraftdiameterSlider.Value;
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelSlider.Value;
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelField.Value;
                app.MaxzSlider_2.Value = app.MaxzSlider.Value;
                app.MaxzField_2.Value = app.MaxzField.Value;
                
                r = app.rSlider.Value * 1000;
                R = (app.DowndraftdiameterSlider_2.Value / 2) * 1000;
                u_max = app.MaxhorizontalvelSlider_2.Value;
                z_max = app.MaxzSlider_2.Value;
                ch_o = z_max / 0.22;
                ch_i = ch_o / 12.5; 
                sf = u_max / (0.2357 * R);
                z = 0:1:1000;

                plot(app.VerticalAxes, compute_u(app, R, ch_o, ch_i, sf, r, z), z, 'k', compute_ur(app, R, ch_o, ch_i, sf, r, z), z, 'r', compute_uz(app, R, ch_o, ch_i, sf, r, z), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Value changed function: DowndraftdiameterField_2, 
        % ...and 3 other components
        function FieldValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            R = (app.DowndraftdiameterField_2.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelField_2.Value;
            z_max = app.MaxzField_2.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            r = app.rField.Value * 1000;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, R, ch_o, ch_i, sf, r, z), z, 'k', compute_ur(app, R, ch_o, ch_i, sf, r, z), z, 'r', compute_uz(app, R, ch_o, ch_i, sf, r, z), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
            
            % Update field values to match sliders
            if app.DowndraftdiameterSlider_2.Value ~= app.DowndraftdiameterField_2.Value
                app.DowndraftdiameterSlider_2.Value = app.DowndraftdiameterField_2.Value;            
            else
            end
            if app.MaxhorizontalvelSlider_2.Value ~= app.MaxhorizontalvelField_2.Value
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelField_2.Value;
            else
            end  
            if app.MaxzSlider_2.Value ~= app.MaxzField_2.Value
                app.MaxzSlider_2.Value = app.MaxzField_2.Value;
            else 
            end
            if app.rSlider.Value ~= app.rField.Value
                app.rSlider.Value = app.rField.Value;
            else
            end
            
            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.DowndraftdiameterField.Value = app.DowndraftdiameterField_2.Value;
                app.DowndraftdiameterSlider.Value = app.DowndraftdiameterSlider_2.Value;
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelField_2.Value;
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelSlider_2.Value;
                app.MaxzField.Value = app.MaxzField_2.Value;
                app.MaxzSlider.Value = app.MaxzSlider_2.Value;
                
                z = app.zField.Value;
                R = (app.DowndraftdiameterField.Value / 2) * 1000;
                u_max = app.MaxhorizontalvelField.Value;
                z_max = app.MaxzField.Value;
                ch_o = z_max / 0.22;
                ch_i = ch_o / 12.5; 
                sf = u_max / (0.2357 * R);
                r = 0:1:10000;

                plot(app.HorizontalAxes, r, compute_u(app, R, ch_o, ch_i, sf, r, z), 'k', r, compute_ur(app, R, ch_o, ch_i, sf, r, z), 'r', r, compute_uz(app, R, ch_o, ch_i, sf, r, z), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Value changed function: DowndraftdiameterSlider_2, 
        % ...and 3 other components
        function SliderValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            R = (app.DowndraftdiameterSlider_2.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelSlider_2.Value;
            z_max = app.MaxzSlider_2.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            r = app.rSlider.Value * 1000;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, R, ch_o, ch_i, sf, r, z), z, 'k', compute_ur(app, R, ch_o, ch_i, sf, r, z), z, 'r', compute_uz(app, R, ch_o, ch_i, sf, r, z), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.DowndraftdiameterSlider_2.Value ~= app.DowndraftdiameterField_2.Value
                app.DowndraftdiameterField_2.Value = app.DowndraftdiameterSlider_2.Value;            
            else
            end
            if app.MaxhorizontalvelSlider_2.Value ~= app.MaxhorizontalvelField_2.Value
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelSlider_2.Value;
            else
            end  
            if app.MaxzSlider_2.Value ~= app.MaxzField_2.Value
                app.MaxzField_2.Value = app.MaxzSlider_2.Value;
            else 
            end
            if app.rSlider.Value ~= app.rField.Value
                app.rField.Value = app.rSlider.Value;
            else
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.DowndraftdiameterSlider.Value = app.DowndraftdiameterSlider_2.Value;
                app.DowndraftdiameterField.Value = app.DowndraftdiameterField_2.Value;
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelSlider_2.Value;
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelField_2.Value;
                app.MaxzSlider.Value = app.MaxzSlider_2.Value;
                app.MaxzField.Value = app.MaxzField_2.Value;

                z = app.zSlider.Value;
                R = (app.DowndraftdiameterSlider.Value / 2) * 1000;
                u_max = app.MaxhorizontalvelSlider.Value;
                z_max = app.MaxzSlider.Value;
                ch_o = z_max / 0.22;
                ch_i = ch_o / 12.5; 
                sf = u_max / (0.2357 * R);
                r = 0:1:10000;
                
                plot(app.HorizontalAxes, r, compute_u(app, R, ch_o, ch_i, sf, r, z), 'k', r, compute_ur(app, R, ch_o, ch_i, sf, r, z), 'r', r, compute_uz(app, R, ch_o, ch_i, sf, r, z), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
            
            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Value changed function: PlothorizontalvelocityCheckBox, 
        % ...and 2 other components
        function CheckBoxValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            R = (app.DowndraftdiameterSlider.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelSlider.Value;
            z_max = app.MaxzSlider.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            z = app.zSlider.Value;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, R, ch_o, ch_i, sf, r, z), 'k', r, compute_ur(app, R, ch_o, ch_i, sf, r, z), 'r', r, compute_uz(app, R, ch_o, ch_i, sf, r, z), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end


            % Define parameters for vertical profile
            z = 0:1:1000;
            R = (app.DowndraftdiameterField_2.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelField_2.Value;
            z_max = app.MaxzField_2.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            r = app.rField.Value * 1000;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, R, ch_o, ch_i, sf, r, z), z, 'k', compute_ur(app, R, ch_o, ch_i, sf, r, z), z, 'r', compute_uz(app, R, ch_o, ch_i, sf, r, z), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            R = (app.DowndraftdiameterSlider.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelSlider.Value;
            z_max = app.MaxzSlider.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            z = app.zSlider.Value;

            % Put x axis values into an array
            r = [0:1:10000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, R, ch_o, ch_i, sf, r, z)];
            h = [compute_ur(app, R, ch_o, ch_i, sf, r, z)];
            v = [compute_uz(app, R, ch_o, ch_i, sf, r, z)];

            % Arrange arrays into a table
            a = [r; m; h; v];
            t = array2table(a.');
            t = renamevars(t, ["Var1", "Var2", "Var3", "Var4"], ["Distance from downdraft center [m]","Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButton_2Pushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.VerticalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.VerticalAxes.XLim;
            figAxes.YLim = app.VerticalAxes.YLim;
            figAxes.ZLim = app.VerticalAxes.ZLim;
            figAxes.DataAspectRatio = app.VerticalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Vertical Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButton_2Pushed(app, event)
            % Define parameters for vertical profile
            R = (app.DowndraftdiameterSlider_2.Value / 2) * 1000;
            u_max = app.MaxhorizontalvelSlider_2.Value;
            z_max = app.MaxzSlider_2.Value;
            ch_o = z_max / 0.22;
            ch_i = ch_o / 12.5; 
            sf = u_max / (0.2357 * R);
            r = app.rSlider.Value * 1000;
            
            % Put y axis values into an array
            z = [0:1:1000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, R, ch_o, ch_i, sf, r, z)];
            h = [compute_ur(app, R, ch_o, ch_i, sf, r, z)];
            v = [compute_uz(app, R, ch_o, ch_i, sf, r, z)];

            % Arrange arrays into a table
            a = [m; h; v; z];
            t = array2table(a.');
            t = renamevars(t, ["Var1", "Var2", "Var3", "Var4"], ["Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]","Height above ground [m]"]);

            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Close request function: UIFigure
        function OsegueraBowlesCloseRequest(app, event)
            % Reenable OB button
            app.CallingApp.OsegueraandBowles1988Button.Enable = 'on';

            % Close OB window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @OsegueraBowlesCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.VerticalAxes = uiaxes(app.UIFigure);
            title(app.VerticalAxes, 'Vertical Profile of Velocity')
            xlabel(app.VerticalAxes, 'Velocity [m/s]')
            ylabel(app.VerticalAxes, 'Height above ground [m]')
            zlabel(app.VerticalAxes, 'Z')
            app.OsegueraandBowles1988Label = uilabel(app.UIFigure);
            app.OsegueraandBowles1988Label.Text = 'Oseguera and Bowles (1988)';
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            app.VerticalProfileModelParametersPanel = uipanel(app.UIFigure);
            app.VerticalProfileModelParametersPanel.Title = 'Vertical Profile: Model Parameters';
            app.HeightofmaximumhorizontalvelocitymLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.HeightofmaximumhorizontalvelocitymLabel_2.Text = {'Height of maximum horizontal velocity [m]'; ''};
            app.MaxzSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxzSlider_2.Limits = [0 1000];
            app.MaxzSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxzSlider_2.Value = 100;
            app.MaximumhorizontalvelocitymsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel_2.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxhorizontalvelSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxhorizontalvelSlider_2.Value = 40;
            app.DowndraftradiuskmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.DowndraftradiuskmLabel_2.Text = 'Downdraft diameter [km]';
            app.DowndraftdiameterSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.DowndraftdiameterSlider_2.Limits = [0 5];
            app.DowndraftdiameterSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.DowndraftdiameterSlider_2.Value = 2;
            app.DowndraftdiameterField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.DowndraftdiameterField_2.Limits = [0 5];
            app.DowndraftdiameterField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.DowndraftdiameterField_2.Value = 2;
            app.MaxhorizontalvelField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField_2.Limits = [0 100];
            app.MaxhorizontalvelField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxhorizontalvelField_2.Value = 40;
            app.MaxzField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxzField_2.Limits = [0 1000];
            app.MaxzField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxzField_2.Value = 100;
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_2Pushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton_2Pushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.RadialProfileModelParametersPanel = uipanel(app.UIFigure);
            app.RadialProfileModelParametersPanel.Title = 'Radial Profile: Model Parameters';
            app.HeightofmaximumhorizontalvelocitymLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.HeightofmaximumhorizontalvelocitymLabel.Text = 'Height of maximum horizontal velocity [m]';
            app.MaxzSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxzSlider.Limits = [0 1000];
            app.MaxzSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxzSlider.Value = 100;
            app.MaximumhorizontalvelocitymsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxhorizontalvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxhorizontalvelSlider.Value = 40;
            app.DowndraftdiameterkmLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.DowndraftdiameterkmLabel.Text = 'Downdraft diameter [km]';
            app.DowndraftdiameterSlider = uislider(app.RadialProfileModelParametersPanel);
            app.DowndraftdiameterSlider.Limits = [0 5];
            app.DowndraftdiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.DowndraftdiameterSlider.Value = 2;
            app.DowndraftdiameterField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.DowndraftdiameterField.Limits = [0 5];
            app.DowndraftdiameterField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.DowndraftdiameterField.Value = 2;
            app.MaxhorizontalvelField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField.Limits = [0 100];
            app.MaxhorizontalvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxhorizontalvelField.Value = 40;
            app.MaxzField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxzField.Limits = [0 1000];
            app.MaxzField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxzField.Value = 100;
            app.Panel = uipanel(app.UIFigure);
            app.zmLabel = uilabel(app.Panel);
            app.zmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.Panel);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.Panel, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zField.Value = 100;
            app.Panel_2 = uipanel(app.UIFigure);
            app.rField = uieditfield(app.Panel_2, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.rField.Value = 1;
            app.zmLabel_2 = uilabel(app.Panel_2);
            app.zmLabel_2.Text = 'Distance from downdraft center [km]';
            app.rSlider = uislider(app.Panel_2);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.rSlider.Value = 1;
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlothorizontalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlothorizontalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlothorizontalvelocityCheckBox.Text = 'Plot horizontal velocity';
            app.PlotverticalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotverticalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotverticalvelocityCheckBox.Text = 'Plot vertical velocity';
            app.Panel_3 = uipanel(app.UIFigure);
            app.FixedParametersCheckBox = uicheckbox(app.Panel_3);
            app.FixedParametersCheckBox.Text = 'Keep model parameters fixed';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://ntrs.nasa.gov/citations/19880018674';
            app.Hyperlink.Text = 'Corresponding Paper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OsegueraBowles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end