classdef Vicroy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        Panel_3                         matlab.ui.container.Panel
        FixedParametersCheckBox         matlab.ui.control.CheckBox
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlotverticalvelocityCheckBox    matlab.ui.control.CheckBox
        PlothorizontalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        rSlider                         matlab.ui.control.Slider
        rField                          matlab.ui.control.NumericEditField
        zmLabel_2                       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        zField                          matlab.ui.control.NumericEditField
        zSlider                         matlab.ui.control.Slider
        zmLabel                         matlab.ui.control.Label
        ExportDataTableButton_2         matlab.ui.control.Button
        ExportPlotButton_2              matlab.ui.control.Button
        VerticalProfileModelParametersPanel  matlab.ui.container.Panel
        Image_2                         matlab.ui.control.Image
        ShapingfunctionvariableSlider_2  matlab.ui.control.Slider
        ShapingfunctionvariableField_2  matlab.ui.control.NumericEditField
        ShapingfunctionvariableSliderLabel_2  matlab.ui.control.Label
        MaxhorizontalvelField_2         matlab.ui.control.NumericEditField
        MaxzSlider_2                    matlab.ui.control.Slider
        HeightofmaximumhorizontalvelocitymLabel_5  matlab.ui.control.Label
        MaxzField_2                     matlab.ui.control.NumericEditField
        MaxrSlider_2                    matlab.ui.control.Slider
        RadiusofmaximumhorizontalvelocitykmLabel_2  matlab.ui.control.Label
        MaxrField_2                     matlab.ui.control.NumericEditField
        MaxhorizontalvelSlider_2        matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel_2  matlab.ui.control.Label
        ExportDataTableButton           matlab.ui.control.Button
        ExportPlotButton                matlab.ui.control.Button
        RadialProfileModelParametersPanel  matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        ShapingfunctionvariableField    matlab.ui.control.NumericEditField
        ShapingfunctionvariableSlider   matlab.ui.control.Slider
        ShapingfunctionvariableSliderLabel  matlab.ui.control.Label
        MaxrSlider                      matlab.ui.control.Slider
        RadiusofmaximumhorizontalvelocitykmLabel  matlab.ui.control.Label
        MaxrField                       matlab.ui.control.NumericEditField
        MaxzField                       matlab.ui.control.NumericEditField
        MaxhorizontalvelField           matlab.ui.control.NumericEditField
        MaxhorizontalvelSlider          matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel  matlab.ui.control.Label
        MaxzSlider                      matlab.ui.control.Slider
        HeightofmaximumhorizontalvelocitymLabel  matlab.ui.control.Label
        Vicroy1992Label                 matlab.ui.control.Label
        VerticalAxes                    matlab.ui.control.UIAxes
        HorizontalAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)
        
        function u = compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z)
            % Horizontal downburst velocity (Equation 14)
            u_r = ((sf .* r) ./ 2) .* (exp(c_1 * (z ./ z_max)) - exp(c_2 * (z ./ z_max))) .* exp((2 - (r.^2/r_max^2).^a) / (2*a));

            % Vertical downburst velocity (Equation 15)
            u_z = -sf * ((z_max/c_1) .* (exp(c_1 * (z./z_max))-1) - (z_max/c_2) .* (exp(c_2 * (z./z_max))-1)) .* (1 - 0.5 .* (r.^2/r_max^2).^a) .* exp((2 - (r.^2/r_max^2).^a) / (2*a));

            % Downburst velocity magnitude 
            u = sqrt(u_r.^2 + u_z.^2);
        end
        
        function u_r = compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z)
            % Horizontal downburst velocity (Equation 14)
            u_r = ((sf .* r) ./ 2) .* (exp(c_1 * (z ./ z_max)) - exp(c_2 * (z ./ z_max))) .* exp((2 - (r.^2/r_max^2).^a) / (2*a));
        end
        
        function u_z = compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z)
            % Vertical downburst velocity (Equation 15)
            u_z = -sf * ((z_max/c_1) .* (exp(c_1 * (z./z_max))-1) - (z_max/c_2) .* (exp(c_2 * (z./z_max))-1)) .* (1 - 0.5 .* (r.^2/r_max^2).^a) .* exp((2 - (r.^2/r_max^2).^a) / (2*a));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller2)
            % Store selection menu app object
            app.CallingApp = caller2;

            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlothorizontalvelocityCheckBox.Value = 1;
            app.PlotverticalvelocityCheckBox.Value = 1;
            app.FixedParametersCheckBox.Value = 1;
            
            % Define parameters for radial profile
            r = 0:1:10000;
            u_max = app.MaxhorizontalvelField.Value;
            r_max = app.MaxrField.Value * 1000;
            z_max = app.MaxzField.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField.Value; % Denoted as alpha in paper
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a)); % from (Vicroy 1991), Equation A1
            z = app.zField.Value;

            % Plot radial profile horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'k', r, compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'r', r, compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r = 0:1:10000;
            m = compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z);
            v_max = max(m, [], 'all');
            v_lim = 0.05 * v_max;
            r_lim = find(m<(v_lim+1) & m>(v_lim-1), 1, 'last');
            xlim(app.HorizontalAxes, [0, (r_lim+500)]);

            % Define parameters for vertical profile
            z = 0:1:1000;
            u_max = app.MaxhorizontalvelField_2.Value;
            r_max = app.MaxrField_2.Value * 1000;
            z_max = app.MaxzField_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField_2.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            r = app.rField.Value * 1000;

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'k', compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'r', compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
        end

        % Value changed function: MaxhorizontalvelField, MaxrField, 
        % ...and 3 other components
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_max = app.MaxhorizontalvelField.Value;
            r_max = app.MaxrField.Value * 1000;
            z_max = app.MaxzField.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            z = app.zField.Value;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'k', r, compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'r', r, compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r = 0:1:10000;
            m = compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z);
            v_max = max(m, [], 'all');
            v_lim = 0.05 * v_max;
            r_lim = find(m<(v_lim+1) & m>(v_lim-1), 1, 'last');
            xlim(app.HorizontalAxes, [0, (r_lim+500)]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update sliders to match field values
            if app.MaxhorizontalvelField.Value ~= app.MaxhorizontalvelSlider.Value
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelField.Value;
            else 
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrSlider.Value = app.MaxrField.Value;
            else
            end
            if app.MaxzField.Value ~= app.MaxzSlider.Value
                app.MaxzSlider.Value = app.MaxzField.Value;
            else 
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            else 
            end
            if app.ShapingfunctionvariableField.Value ~= app.ShapingfunctionvariableSlider.Value
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableField.Value;
            else
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxrField_2.Value = app.MaxrField.Value;
                app.MaxrSlider_2.Value = app.MaxrSlider.Value;
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelField.Value;
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelSlider.Value;
                app.MaxzField_2.Value = app.MaxzField.Value;
                app.MaxzSlider_2.Value = app.MaxzSlider.Value;
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableField.Value;
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableSlider.Value;

                z = 0:1:1000;
                u_max = app.MaxhorizontalvelField_2.Value;
                r_max = app.MaxrField_2.Value * 1000;
                z_max = app.MaxzField_2.Value;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableField_2.Value;
                sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
                r = app.rField.Value * 1000;

                plot(app.VerticalAxes, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'k', compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'r', compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: MaxhorizontalvelSlider, MaxrSlider, 
        % ...and 3 other components
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_max = app.MaxhorizontalvelSlider.Value;
            r_max = app.MaxrSlider.Value * 1000;
            z_max = app.MaxzSlider.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            z = app.zSlider.Value;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'k', r, compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'r', r, compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');
            
            % Adjust axis for radial profile
            r = 0:1:10000;
            m = compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z);
            v_max = max(m, [], 'all');
            v_lim = 0.05 * v_max;
            r_lim = find(m<(v_lim+1) & m>(v_lim-1), 1, 'last');
            xlim(app.HorizontalAxes, [0, (r_lim+500)]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxhorizontalvelSlider.Value ~= app.MaxhorizontalvelField.Value
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelSlider.Value;
            else
            end  
            if app.MaxrSlider.Value ~= app.MaxrField.Value
                app.MaxrField.Value = app.MaxrSlider.Value;            
            else
            end
            if app.MaxzSlider.Value ~= app.MaxzField.Value
                app.MaxzField.Value = app.MaxzSlider.Value;
            else 
            end
            if app.zSlider.Value ~= app.zField.Value
                app.zField.Value = app.zSlider.Value;
            else
            end
            if app.ShapingfunctionvariableSlider.Value ~= app.ShapingfunctionvariableField.Value
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableSlider.Value;
            else
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxrSlider_2.Value = app.MaxrSlider.Value;
                app.MaxrField_2.Value = app.MaxrField.Value;
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelSlider.Value;
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelField.Value;
                app.MaxzSlider_2.Value = app.MaxzSlider.Value;
                app.MaxzField_2.Value = app.MaxzField.Value;
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableField.Value;
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableSlider.Value;

                z = 0:1:1000;
                u_max = app.MaxhorizontalvelSlider_2.Value;
                r_max = app.MaxrSlider_2.Value * 1000;
                z_max = app.MaxzSlider_2.Value;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableSlider_2.Value;
                sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
                r = app.rField.Value * 1000;

                plot(app.VerticalAxes, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'k', compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'r', compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: MaxhorizontalvelField_2, MaxrField_2, 
        % ...and 3 other components
        function FieldValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            u_max = app.MaxhorizontalvelField_2.Value;
            r_max = app.MaxrField_2.Value * 1000;
            z_max = app.MaxzField_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField_2.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            r = app.rField.Value * 1000;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'k', compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'r', compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
            
            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxhorizontalvelField_2.Value ~= app.MaxhorizontalvelSlider_2.Value
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelField_2.Value;
            else
            end
            if app.MaxrField_2.Value ~= app.MaxrSlider_2.Value
                app.MaxrSlider_2.Value = app.MaxrField_2.Value;            
            else
            end
            if app.MaxzField_2.Value ~= app.MaxzSlider_2.Value
                app.MaxzSlider_2.Value = app.MaxzField_2.Value;
            else 
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            else
            end
            if app.ShapingfunctionvariableField_2.Value ~= app.ShapingfunctionvariableSlider_2.Value
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableField_2.Value;
            else
            end
            
            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxrField.Value = app.MaxrField_2.Value;
                app.MaxrSlider.Value = app.MaxrSlider_2.Value;
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelField_2.Value;
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelSlider_2.Value;
                app.MaxzField.Value = app.MaxzField_2.Value;
                app.MaxzSlider.Value = app.MaxzSlider_2.Value;
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableField_2.Value;
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableSlider_2.Value;

                r = 0:1:10000;
                u_max = app.MaxhorizontalvelField.Value;
                r_max = app.MaxrField.Value * 1000;
                z_max = app.MaxzField.Value;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableField.Value;
                sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
                z = app.zField.Value;

                plot(app.HorizontalAxes, r, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'k', r, compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'r', r, compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');

                r = 0:1:10000;
                m = [compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z)];
                v_max = max(m, [], 'all');
                v_lim = 0.05 * v_max;
                r_lim = find(m<(v_lim+1) & m>(v_lim-1), 1, 'last');
                xlim(app.HorizontalAxes, [0, (r_lim+500)]);

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: MaxhorizontalvelSlider_2, MaxrSlider_2, 
        % ...and 3 other components
        function SliderValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:1:1000;
            u_max = app.MaxhorizontalvelSlider_2.Value;
            r_max = app.MaxrSlider_2.Value * 1000;
            z_max = app.MaxzSlider_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider_2.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            r = app.rSlider.Value * 1000;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'k', compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'r', compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
            
            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxhorizontalvelSlider_2.Value ~= app.MaxhorizontalvelField_2.Value
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelSlider_2.Value;
            else
            end  
            if app.MaxrSlider_2.Value ~= app.MaxrField_2.Value
                app.MaxrField_2.Value = app.MaxrSlider_2.Value;
            else
            end
            if app.MaxzSlider_2.Value ~= app.MaxzField_2.Value
                app.MaxzField_2.Value = app.MaxzSlider_2.Value;
            else 
            end
            if app.rSlider.Value ~= app.rField.Value
                app.rField.Value = app.rSlider.Value;
            else
            end
            if app.ShapingfunctionvariableSlider_2.Value ~= app.ShapingfunctionvariableField_2.Value
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableSlider_2.Value;
            else
            end

            % Update field value if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelSlider_2.Value;
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelField_2.Value;
                app.MaxrSlider.Value = app.MaxrSlider_2.Value;
                app.MaxrField.Value = app.MaxrField_2.Value;
                app.MaxzSlider.Value = app.MaxzSlider_2.Value;
                app.MaxzField.Value = app.MaxzField_2.Value;
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableSlider_2.Value;
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableField_2.Value;

                r = 0:1:10000;
                u_max = app.MaxhorizontalvelSlider.Value;
                r_max = app.MaxrSlider.Value * 1000;
                z_max = app.MaxzSlider.Value;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableSlider.Value;
                sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
                z = app.zSlider.Value;

                plot(app.HorizontalAxes, r, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'k', r, compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'r', r, compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');

                r = 0:1:10000;
                m = [compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z)];
                v_max = max(m, [], 'all');
                v_lim = 0.05 * v_max;
                r_lim = find(m<(v_lim+1) & m>(v_lim-1), 1, 'last');
                xlim(app.HorizontalAxes, [0, (r_lim+500)]);

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: PlothorizontalvelocityCheckBox, 
        % ...and 2 other components
        function CheckBoxValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            u_max = app.MaxhorizontalvelField.Value;
            r_max = app.MaxrField.Value * 1000;
            z_max = app.MaxzField.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            z = app.zField.Value;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'k', r, compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'r', r, compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r = 0:1:10000;
            m = compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z);
            v_max = max(m, [], 'all');
            v_lim = 0.05 * v_max;
            r_lim = find(m<(v_lim+1) & m>(v_lim-1), 1, 'last');
            xlim(app.HorizontalAxes, [0, (r_lim+500)]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end


            % Define parameters for vertical profile
            z = 0:1:1000;
            u_max = app.MaxhorizontalvelField_2.Value;
            r_max = app.MaxrField_2.Value * 1000;
            z_max = app.MaxzField_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField_2.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            r = app.rField.Value * 1000;

            % Plot horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'k', compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'r', compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
            
            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end
            
            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            u_max = app.MaxhorizontalvelSlider.Value;
            r_max = app.MaxrSlider.Value * 1000;
            z_max = app.MaxzSlider.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            z = app.zSlider.Value;

            % Put x axis values into an array
            r = [0:1:10000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z)];
            h = [compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z)];
            v = [compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z)];

            % Arrange arrays into a table
            ar = [r; m; h; v];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2", "Var3", "Var4"], ["Distance from downdraft center [m]","Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButton_2Pushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.VerticalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.VerticalAxes.XLim;
            figAxes.YLim = app.VerticalAxes.YLim;
            figAxes.ZLim = app.VerticalAxes.ZLim;
            figAxes.DataAspectRatio = app.VerticalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Vertical Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end
            
            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButton_2Pushed(app, event)
            % Define parameters for vertical profile
            u_max = app.MaxhorizontalvelSlider_2.Value;
            r_max = app.MaxrSlider_2.Value * 1000;
            z_max = app.MaxzSlider_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider_2.Value;
            sf = (2 * u_max) / (r_max * (exp(c_1)-exp(c_2)) * exp(0.5 / a));
            r = app.rSlider.Value * 1000;
            
            % Put y axis values into an array
            z = [0:1:1000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, c_1, c_2, z_max, r_max, sf, a, r, z)];
            h = [compute_ur(app, c_1, c_2, z_max, r_max, sf, a, r, z)];
            v = [compute_uz(app, c_1, c_2, z_max, r_max, sf, a, r, z)];

            % Arrange arrays into a table
            ar = [m; h; v; z];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2", "Var3", "Var4"], ["Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]","Height above ground [m]"]);

            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= ""
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Close request function: UIFigure
        function VicroyCloseRequest(app, event)
            % Reenable OBV button
            app.CallingApp.Vicroy1992Button.Enable = 'on';

            % Close OBV window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @VicroyCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.VerticalAxes = uiaxes(app.UIFigure);
            title(app.VerticalAxes, 'Vertical Profile of Velocity')
            xlabel(app.VerticalAxes, 'Velocity [m/s]')
            ylabel(app.VerticalAxes, 'Height above ground [m]')
            zlabel(app.VerticalAxes, 'Z')
            app.Vicroy1992Label = uilabel(app.UIFigure);
            app.Vicroy1992Label.Text = 'Vicroy (1992)';
            app.RadialProfileModelParametersPanel = uipanel(app.UIFigure);
            app.RadialProfileModelParametersPanel.Title = 'Radial Profile: Model Parameters';
            app.HeightofmaximumhorizontalvelocitymLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.HeightofmaximumhorizontalvelocitymLabel.Text = 'Height of maximum horizontal velocity [m]';
            app.MaxzSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxzSlider.Limits = [0 1000];
            app.MaxzSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxzSlider.Value = 100;
            app.MaximumhorizontalvelocitymsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxhorizontalvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxhorizontalvelSlider.Value = 40;
            app.MaxhorizontalvelField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField.Limits = [0 100];
            app.MaxhorizontalvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxhorizontalvelField.Value = 40;
            app.MaxzField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxzField.Limits = [0 1000];
            app.MaxzField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxzField.Value = 100;
            app.MaxrField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxrField.Limits = [0 5];
            app.MaxrField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxrField.Value = 1;
            app.RadiusofmaximumhorizontalvelocitykmLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.RadiusofmaximumhorizontalvelocitykmLabel.Text = 'Radius of maximum horizontal velocity [km]';
            app.MaxrSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxrSlider.Limits = [0 5];
            app.MaxrSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxrSlider.Value = 1;
            app.ShapingfunctionvariableSliderLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.ShapingfunctionvariableSliderLabel.Tooltip = {''};
            app.ShapingfunctionvariableSliderLabel.Text = 'Shaping function ';
            app.ShapingfunctionvariableSlider = uislider(app.RadialProfileModelParametersPanel);
            app.ShapingfunctionvariableSlider.Limits = [1 4];
            app.ShapingfunctionvariableSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ShapingfunctionvariableSlider.Tooltip = {''};
            app.ShapingfunctionvariableSlider.Value = 2;
            app.ShapingfunctionvariableField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.ShapingfunctionvariableField.Limits = [1 4];
            app.ShapingfunctionvariableField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ShapingfunctionvariableField.Tooltip = {''};
            app.ShapingfunctionvariableField.Value = 2;
            app.Image = uiimage(app.RadialProfileModelParametersPanel);
            app.Image.Tooltip = {'Default value is 2.'};
            app.Image.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            app.VerticalProfileModelParametersPanel = uipanel(app.UIFigure);
            app.VerticalProfileModelParametersPanel.Title = 'Vertical Profile: Model Parameters';
            app.MaximumhorizontalvelocitymsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel_2.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxhorizontalvelSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxhorizontalvelSlider_2.Value = 40;
            app.MaxrField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxrField_2.Limits = [0 5];
            app.MaxrField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxrField_2.Value = 1;
            app.RadiusofmaximumhorizontalvelocitykmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.RadiusofmaximumhorizontalvelocitykmLabel_2.Text = 'Radius of maximum horizontal velocity [km]';
            app.MaxrSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxrSlider_2.Limits = [0 5];
            app.MaxrSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxrSlider_2.Value = 1;
            app.MaxzField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxzField_2.Limits = [0 500];
            app.MaxzField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxzField_2.Value = 100;
            app.HeightofmaximumhorizontalvelocitymLabel_5 = uilabel(app.VerticalProfileModelParametersPanel);
            app.HeightofmaximumhorizontalvelocitymLabel_5.Text = 'Height of maximum horizontal velocity [m]';
            app.MaxzSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxzSlider_2.Limits = [0 500];
            app.MaxzSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxzSlider_2.Value = 100;
            app.MaxhorizontalvelField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField_2.Limits = [0 100];
            app.MaxhorizontalvelField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxhorizontalvelField_2.Value = 40;
            app.ShapingfunctionvariableSliderLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.ShapingfunctionvariableSliderLabel_2.Tooltip = {''};
            app.ShapingfunctionvariableSliderLabel_2.Text = 'Shaping function';
            app.ShapingfunctionvariableField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.ShapingfunctionvariableField_2.Limits = [1 4];
            app.ShapingfunctionvariableField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.ShapingfunctionvariableField_2.Tooltip = {''};
            app.ShapingfunctionvariableField_2.Value = 2;
            app.ShapingfunctionvariableSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.ShapingfunctionvariableSlider_2.Limits = [1 4];
            app.ShapingfunctionvariableSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.ShapingfunctionvariableSlider_2.Tooltip = {''};
            app.ShapingfunctionvariableSlider_2.Value = 2;
            app.Image_2 = uiimage(app.VerticalProfileModelParametersPanel);
            app.Image_2.Tooltip = {'Default value is 2.'};
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_2Pushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton_2Pushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.Panel = uipanel(app.UIFigure);
            app.zmLabel = uilabel(app.Panel);
            app.zmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.Panel);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.Panel, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zField.Value = 100;
            app.Panel_2 = uipanel(app.UIFigure);
            app.zmLabel_2 = uilabel(app.Panel_2);
            app.zmLabel_2.Text = 'Distance from downdraft center [km]';
            app.rField = uieditfield(app.Panel_2, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.rField.Value = 1;
            app.rSlider = uislider(app.Panel_2);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.rSlider.Value = 1;
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlothorizontalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlothorizontalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlothorizontalvelocityCheckBox.Text = 'Plot horizontal velocity';
            app.PlotverticalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotverticalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotverticalvelocityCheckBox.Text = 'Plot vertical velocity';
            app.Panel_3 = uipanel(app.UIFigure);
            app.FixedParametersCheckBox = uicheckbox(app.Panel_3);
            app.FixedParametersCheckBox.Text = 'Keep model parameters fixed';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.2514/3.46282';
            app.Hyperlink.Text = 'Corresponding Paper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vicroy(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end