classdef Xhelaj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SchematicofSomeModelParametersPanel  matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        Panel_2                         matlab.ui.container.Panel
        AngleField                      matlab.ui.control.NumericEditField
        AngleSlider                     matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_5  matlab.ui.control.Label
        ExportDataTableButton           matlab.ui.control.Button
        ExportPlotButton                matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlotverticalvelocityCheckBox    matlab.ui.control.CheckBox
        PlothorizontalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        RadialProfileModelParametersPanel  matlab.ui.container.Panel
        RadialPositionSlider            matlab.ui.control.Slider
        DimensionlessradialpositionofmaximumhorizontalvelocityLabel  matlab.ui.control.Label
        RadialPositionField             matlab.ui.control.NumericEditField
        Image_2                         matlab.ui.control.Image
        TimeField                       matlab.ui.control.NumericEditField
        TimeSlider                      matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_4  matlab.ui.control.Label
        MaxoutflowvelField              matlab.ui.control.NumericEditField
        MaxoutflowvelSlider             matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel  matlab.ui.control.Label
        rsSlider                        matlab.ui.control.Slider
        RadiallengthscalekmLabel        matlab.ui.control.Label
        rsField                         matlab.ui.control.NumericEditField
        TvelSlider                      matlab.ui.control.Slider
        TranslationalvelocityofstormmsLabel  matlab.ui.control.Label
        TvelField                       matlab.ui.control.NumericEditField
        ABLvelSlider                    matlab.ui.control.Slider
        ABLwindspeedmsLabel             matlab.ui.control.Label
        ABLvelField                     matlab.ui.control.NumericEditField
        TAngleSlider                    matlab.ui.control.Slider
        AngleofstormtranslationdegreeLabel  matlab.ui.control.Label
        TAngleField                     matlab.ui.control.NumericEditField
        ABLAngleSlider                  matlab.ui.control.Slider
        AngleofABLwinddegreeLabel       matlab.ui.control.Label
        ABLAngleField                   matlab.ui.control.NumericEditField
        TotaltimeField                  matlab.ui.control.NumericEditField
        TotaltimeSlider                 matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_3  matlab.ui.control.Label
        IntensificationtimeField        matlab.ui.control.NumericEditField
        IntensificationtimeSlider       matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel  matlab.ui.control.Label
        Xhelajetal2020Label             matlab.ui.control.Label
        HorizontalAxes                  matlab.ui.control.UIAxes
    end

   % Plot should be time vs. velocity
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)

        function u_x = compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Horizontal component (Equation 13)
            v_x = (v_r .* if_r .* cosd(br)) + (v_t .* cosd(bt) .* del .* if_t) + (v_b .* cosd(bb));

            % Vertical component (Equation 13)
            v_y = (v_r .* if_r .* sind(br)) + (v_t .* sind(bt) .* del .* if_t) + (v_b .* sind(bb));

            % Cartestian to polar conversion
            u_x = cosd(br).*v_x + sind(br).*v_y;
        end

        function u_y = compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Horizontal component (Equation 13)
            v_x = (v_r .* if_r .* cosd(br)) + (v_t .* cosd(bt) .* del .* if_t) + (v_b .* cosd(bb));

            % Vertical component (Equation 13)
            v_y = (v_r .* if_r .* sind(br)) + (v_t .* sind(bt) .* del .* if_t) + (v_b .* sind(bb));

            % Cartesian to polar conversion
            u_y = -sind(br).*v_x + cosd(br).*v_y;
        end

        function u = compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Horizontal component (Equation 13) 
            v_x = (v_r .* if_r .* cosd(br)) + (v_t .* cosd(bt) .* del .* if_t) + (v_b .* cosd(bb));
            % Vertical component (Equation 13)
            v_y = (v_r .* if_r .* sind(br)) + (v_t .* sind(bt) .* del .* if_t) + (v_b .* sind(bb));

            % Cartestian to polar conversions
            u_x = cosd(br).*v_x + sind(br).*v_y;
            u_y = -sind(br).*v_x + cosd(br).*v_y;

            % Magnitude
            u = sqrt(u_x.^2 + u_y.^2);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller10)
            % Store selection menu app object
            app.CallingApp = caller10;
            
            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlothorizontalvelocityCheckBox.Value = 1;
            app.PlotverticalvelocityCheckBox.Value = 1;

            % Define parameters for radial profile
            r = 0:1:10000;
            r_s = app.rsSlider.Value * 1000; % Radial length scale
            ur_max = app.MaxoutflowvelSlider.Value;
            v_t = app.TvelSlider.Value; % Translational velocity 
            bt = app.TAngleSlider.Value; % Angle of storm translation
            v_b = app.ABLvelSlider.Value; % ABL wind speed
            bb = app.ABLAngleSlider.Value; % Angle of ABL wind
            t = app.TimeSlider.Value; % Current time 
            t_int = app.IntensificationtimeSlider.Value; % Time to storm intensification
            t_f = app.TotaltimeSlider.Value; % Time duration of storm
            if t <= t_int % if_r (Equation 3)
                if_r = t / t_int;
            else 
                if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
            end
            t_intr = 0.2 * t_int; 
            t_fmax = 1.2 * t_f;
            if (0 <= t && t <= t_intr) % if_t (Equation 10)
                if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
            elseif (t_intr < t && t <= t_f)
                if_t = 1;
            elseif (t_f < t && t <= t_fmax)
                if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
            elseif t > t_fmax
                if_t = 0;
            end
            br = app.AngleSlider.Value; % Azimuth angle
            p = app.RadialPositionSlider.Value; % Dimensionless radial position of maximum horizontal velocity, denoted as rho in paper (Table 5 in Xhelaj (2022))
            r_max = p * r_s;
            r_e = p * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            v_r = zeros(1, length(r)); % Equation 2
            for i = 1:length(r)
                if r(i) <= r_max
                    v_r(i) = ur_max * (r(i)/r_max);
                else
                    v_r(i) = ur_max * exp(-((r(i) - r_max)/r_s)^2);
                end
            end
            del = zeros(1, length(r)); % Equation 7
            for i = 1:length(r)
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', r, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', r, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component');
            legend(app.HorizontalAxes, 'Location', 'southoutside');
        end

        % Value changed function: ABLAngleSlider, ABLvelSlider, 
        % ...and 9 other components
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            r_s = app.rsSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t = app.TimeSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            if t <= t_int 
                if_r = t / t_int;
            else 
                if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
            end
            t_intr = 0.2 * t_int; 
            t_fmax = 1.2 * t_f;
            if (0 <= t && t <= t_intr) 
                if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
            elseif (t_intr < t && t <= t_f)
                if_t = 1;
            elseif (t_f < t && t <= t_fmax)
                if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
            elseif t > t_fmax
                if_t = 0;
            end
            br = app.AngleSlider.Value; 
            p = app.RadialPositionSlider.Value; 
            r_max = p * r_s;
            r_e = p * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            v_r = zeros(1, length(r)); 
            for i = 1:length(r)
                if r(i) <= r_max
                    v_r(i) = ur_max * (r(i)/r_max);
                else
                    v_r(i) = ur_max * exp(-((r(i) - r_max)/r_s)^2);
                end
            end
            del = zeros(1, length(r)); 
            for i = 1:length(r)
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', r, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', r, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelField.Value = app.MaxoutflowvelSlider.Value;
            else
            end
            if app.rsField.Value ~= app.rsSlider.Value
                app.rsField.Value = app.rsSlider.Value;
            else
            end
            if app.AngleField.Value ~= app.AngleSlider.Value
                app.AngleField.Value = app.AngleSlider.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeField.Value = app.IntensificationtimeSlider.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeField.Value = app.TotaltimeSlider.Value;
            else
            end
            if app.TAngleField.Value ~= app.TAngleSlider.Value
                app.TAngleField.Value = app.TAngleSlider.Value;
            else
            end
            if app.ABLAngleField.Value ~= app.ABLAngleSlider.Value
                app.ABLAngleField.Value = app.ABLAngleSlider.Value;
            else
            end
            if app.TvelField.Value ~= app.TvelSlider.Value
                app.TvelField.Value = app.TvelSlider.Value;
            else
            end
            if app.ABLvelField.Value ~= app.ABLvelSlider.Value
                app.ABLvelField.Value = app.ABLvelSlider.Value;
            else
            end
            if app.TimeField.Value ~= app.TimeSlider.Value
                app.TimeField.Value = app.TimeSlider.Value;
            end
            if app.RadialPositionField.Value ~= app.RadialPositionSlider.Value
                app.RadialPositionField.Value = app.RadialPositionSlider.Value;
            end
        end

        % Value changed function: ABLAngleField, ABLvelField, AngleField, 
        % ...and 8 other components
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            r_s = app.rsField.Value * 1000;
            ur_max = app.MaxoutflowvelField.Value;
            v_t = app.TvelField.Value;
            bt = app.TAngleField.Value;
            v_b = app.ABLvelField.Value;
            bb = app.ABLAngleField.Value;
            t = app.TimeField.Value;
            t_int = app.IntensificationtimeField.Value;
            t_f = app.TotaltimeField.Value;
            if t <= t_int 
                if_r = t / t_int;
            else 
                if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
            end
            t_intr = 0.2 * t_int; 
            t_fmax = 1.2 * t_f;
            if (0 <= t && t <= t_intr) 
                if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
            elseif (t_intr < t && t <= t_f)
                if_t = 1;
            elseif (t_f < t && t <= t_fmax)
                if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
            elseif t > t_fmax
                if_t = 0;
            end
            br = app.AngleField.Value; 
            p = app.RadialPositionSlider.Value; 
            r_max = p * r_s;
            r_e = p * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            v_r = zeros(1, length(r)); 
            for i = 1:length(r)
                if r(i) <= r_max
                    v_r(i) = ur_max * (r(i)/r_max);
                else
                    v_r(i) = ur_max * exp(-((r(i) - r_max)/r_s)^2);
                end
            end
            del = zeros(1, length(r)); 
            for i = 1:length(r)
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', r, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', r, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelField.Value;
            else
            end
            if app.rsField.Value ~= app.rsSlider.Value
                app.rsSlider.Value = app.rsField.Value;
            else
            end
            if app.AngleField.Value ~= app.AngleSlider.Value
                app.AngleSlider.Value = app.AngleField.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeSlider.Value = app.IntensificationtimeField.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeSlider.Value = app.TotaltimeField.Value;
            else
            end
            if app.TAngleField.Value ~= app.TAngleSlider.Value
                app.TAngleSlider.Value = app.TAngleField.Value;
            else
            end
            if app.ABLAngleField.Value ~= app.ABLAngleSlider.Value
                app.ABLAngleSlider.Value = app.ABLAngleField.Value;
            else
            end
            if app.TvelField.Value ~= app.TvelSlider.Value
                app.TvelSlider.Value = app.TvelField.Value;
            else
            end
            if app.ABLvelField.Value ~= app.ABLvelSlider.Value
                app.ABLvelSlider.Value = app.ABLvelField.Value;
            else
            end
            if app.TimeField.Value ~= app.TimeSlider.Value
                app.TimeSlider.Value = app.TimeField.Value;
            end
            if app.RadialPositionField.Value ~= app.RadialPositionSlider.Value
                app.RadialPositionSlider.Value = app.RadialPositionField.Value;
            end
        end

        % Value changed function: PlothorizontalvelocityCheckBox, 
        % ...and 2 other components
        function CheckBoxValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            r_s = app.rsSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t = app.TimeSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            if t <= t_int 
                if_r = t / t_int;
            else 
                if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
            end
            t_intr = 0.2 * t_int; 
            t_fmax = 1.2 * t_f;
            if (0 <= t && t <= t_intr) 
                if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
            elseif (t_intr < t && t <= t_f)
                if_t = 1;
            elseif (t_f < t && t <= t_fmax)
                if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
            elseif t > t_fmax
                if_t = 0;
            end
            br = app.AngleSlider.Value; 
            p = app.RadialPositionSlider.Value; 
            r_max = p * r_s;
            r_e = p * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            v_r = zeros(1, length(r)); 
            for i = 1:length(r)
                if r(i) <= r_max
                    v_r(i) = ur_max * (r(i)/r_max);
                else
                    v_r(i) = ur_max * exp(-((r(i) - r_max)/r_s)^2);
                end
            end
            del = zeros(1, length(r)); 
            for i = 1:length(r)
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', r, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', r, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Radial Component', 'Tangential Component');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the time plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Radial Component', 'Tangential Component');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end
            
            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            r = 0:1:10000;
            r_s = app.rsSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t = app.TimeSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            if t <= t_int 
                if_r = t / t_int;
            else 
                if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
            end
            t_intr = 0.2 * t_int; 
            t_fmax = 1.2 * t_f;
            if (0 <= t && t <= t_intr) 
                if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
            elseif (t_intr < t && t <= t_f)
                if_t = 1;
            elseif (t_f < t && t <= t_fmax)
                if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
            elseif t > t_fmax
                if_t = 0;
            end
            br = app.AngleSlider.Value; 
            p = app.RadialPositionSlider.Value; 
            r_max = p * r_s;
            r_e = p * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            v_r = zeros(1, length(r)); 
            for i = 1:length(r)
                if r(i) <= r_max
                    v_r(i) = ur_max * (r(i)/r_max);
                else
                    v_r(i) = ur_max * exp(-((r(i) - r_max)/r_s)^2);
                end
            end
            del = zeros(1, length(r)); 
            for i = 1:length(r)
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Put x axis values into an array
            r = [0:1:10000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];
            zon = [compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];
            mer = [compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];

            % Arrange arrays into a table
            ar = [r; m; zon; mer];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2", "Var3", "Var4"], ["Distance from downdraft center [m]","Magnitude [m/s]","Radial Component [m/s]","Tangential Component [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(tbl, filenameFull)
            end
        end

        % Close request function: UIFigure
        function XhelajCloseRequest(app, event)
            % Reenable Xhelaj button
            app.CallingApp.Xhelajetal2020Button.Enable = 'on';

            % Close Xhelaj window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @XhelajCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.Xhelajetal2020Label = uilabel(app.UIFigure);
            app.Xhelajetal2020Label.Text = 'Xhelaj et al. (2020)';
            app.RadialProfileModelParametersPanel = uipanel(app.UIFigure);
            app.RadialProfileModelParametersPanel.Title = 'Radial Profile: Model Parameters';
            app.LengthofstormintensificationminSliderLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel.Text = 'Storm intensification time [min]';
            app.IntensificationtimeSlider = uislider(app.RadialProfileModelParametersPanel);
            app.IntensificationtimeSlider.Limits = [0 15];
            app.IntensificationtimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.IntensificationtimeSlider.Value = 5;
            app.IntensificationtimeField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.IntensificationtimeField.Limits = [0 15];
            app.IntensificationtimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.IntensificationtimeField.Value = 5;
            app.LengthofstormintensificationminSliderLabel_3 = uilabel(app.RadialProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_3.Text = 'Storm duration [min] ';
            app.TotaltimeSlider = uislider(app.RadialProfileModelParametersPanel);
            app.TotaltimeSlider.Limits = [0 30];
            app.TotaltimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TotaltimeSlider.Value = 15;
            app.TotaltimeField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.TotaltimeField.Limits = [0 30];
            app.TotaltimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TotaltimeField.Value = 15;
            app.ABLAngleField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.ABLAngleField.Limits = [0 360];
            app.ABLAngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ABLAngleField.Value = 45;
            app.AngleofABLwinddegreeLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.AngleofABLwinddegreeLabel.Text = 'Angle of ABL wind [degree]';
            app.ABLAngleSlider = uislider(app.RadialProfileModelParametersPanel);
            app.ABLAngleSlider.Limits = [0 360];
            app.ABLAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ABLAngleSlider.Value = 45;
            app.TAngleField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.TAngleField.Limits = [0 360];
            app.TAngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TAngleField.Value = 45;
            app.AngleofstormtranslationdegreeLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.AngleofstormtranslationdegreeLabel.Text = 'Angle of storm translation [degree]';
            app.TAngleSlider = uislider(app.RadialProfileModelParametersPanel);
            app.TAngleSlider.Limits = [0 360];
            app.TAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TAngleSlider.Value = 45;
            app.ABLvelField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.ABLvelField.Limits = [0 30];
            app.ABLvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ABLvelField.Value = 5;
            app.ABLwindspeedmsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.ABLwindspeedmsLabel.Text = 'ABL wind speed [m/s]';
            app.ABLvelSlider = uislider(app.RadialProfileModelParametersPanel);
            app.ABLvelSlider.Limits = [0 30];
            app.ABLvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ABLvelSlider.Value = 5;
            app.TvelField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.TvelField.Limits = [0 30];
            app.TvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TvelField.Value = 10;
            app.TranslationalvelocityofstormmsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.TranslationalvelocityofstormmsLabel.Text = 'Translational velocity of storm [m/s]';
            app.TvelSlider = uislider(app.RadialProfileModelParametersPanel);
            app.TvelSlider.Limits = [0 30];
            app.TvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TvelSlider.Value = 10;
            app.rsField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.rsField.Limits = [0 5];
            app.rsField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rsField.Value = 0.5;
            app.RadiallengthscalekmLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.RadiallengthscalekmLabel.Text = 'Radial length scale [km]';
            app.rsSlider = uislider(app.RadialProfileModelParametersPanel);
            app.rsSlider.Limits = [0 5];
            app.rsSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rsSlider.Value = 0.5;
            app.MaximumhorizontalvelocitymsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxoutflowvelSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxoutflowvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxoutflowvelSlider.Value = 40;
            app.MaxoutflowvelField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxoutflowvelField.Limits = [0 100];
            app.MaxoutflowvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxoutflowvelField.Value = 40;
            app.LengthofstormintensificationminSliderLabel_4 = uilabel(app.RadialProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_4.Text = 'Storm current time [min] ';
            app.TimeSlider = uislider(app.RadialProfileModelParametersPanel);
            app.TimeSlider.Limits = [0 30];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TimeSlider.Value = 10;
            app.TimeField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.TimeField.Limits = [0 30];
            app.TimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TimeField.Value = 10;
            app.Image_2 = uiimage(app.RadialProfileModelParametersPanel);
            app.Image_2.Tooltip = {'Storm intensification time is typically 5 minutes. Over 90% of storms reached maximum intensification within 10 minutes (Hjelmfelt 1988; Wilson et al. 1984).'};
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'infobox_icon.svg');
            app.RadialPositionField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.RadialPositionField.Limits = [1.7 2.5];
            app.RadialPositionField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.RadialPositionField.Value = 2;
            app.DimensionlessradialpositionofmaximumhorizontalvelocityLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.DimensionlessradialpositionofmaximumhorizontalvelocityLabel.Text = {'Dimensionless radial position of maximum '; 'horizontal velocity'};
            app.RadialPositionSlider = uislider(app.RadialProfileModelParametersPanel);
            app.RadialPositionSlider.Limits = [1.7 2.5];
            app.RadialPositionSlider.MajorTicks = [1.7 1.9 2.1 2.3 2.5];
            app.RadialPositionSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.RadialPositionSlider.Value = 2;
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlothorizontalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlothorizontalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlothorizontalvelocityCheckBox.Text = 'Plot horizontal velocity';
            app.PlotverticalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotverticalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotverticalvelocityCheckBox.Text = 'Plot vertical velocity';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.jweia.2020.104373';
            app.Hyperlink.Text = 'Corresponding Paper';
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.LengthofstormintensificationminSliderLabel_5 = uilabel(app.Panel_2);
            app.LengthofstormintensificationminSliderLabel_5.Text = 'Azimuth angle [degrees]';
            app.AngleSlider = uislider(app.Panel_2);
            app.AngleSlider.Limits = [0 360];
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AngleField = uieditfield(app.Panel_2, 'numeric');
            app.AngleField.Limits = [0 360];
            app.AngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.SchematicofSomeModelParametersPanel = uipanel(app.UIFigure);
            app.SchematicofSomeModelParametersPanel.Title = 'Schematic of Some Model Parameters';
            app.Image2 = uiimage(app.SchematicofSomeModelParametersPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'DownburstTranslationSchematic.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xhelaj(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end